classdef GCodeGen_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        WarningText                     matlab.ui.control.TextArea
        GuideText                       matlab.ui.control.TextArea
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        GCodeGeneratorTab               matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        GreetingText                    matlab.ui.control.TextArea
        ModelTab                        matlab.ui.container.Tab
        ModelTabGrid                    matlab.ui.container.GridLayout
        GeometryDropDown                matlab.ui.control.DropDown
        GeometryDropDownLabel           matlab.ui.control.Label
        GenerateProfilesButton          matlab.ui.control.Button
        LoadSTLButton                   matlab.ui.control.Button
        MinEdgeSizemmSpinner            matlab.ui.control.Spinner
        MinEdgeSizemmSpinnerLabel       matlab.ui.control.Label
        TipPlaneXcoordmmEditField       matlab.ui.control.NumericEditField
        TipPlaneXcoordmmEditFieldLabel  matlab.ui.control.Label
        RootPlaneXcoordmmEditField      matlab.ui.control.NumericEditField
        RootPlaneXcoordmmEditFieldLabel  matlab.ui.control.Label
        RotateZSpinner                  matlab.ui.control.Spinner
        RotateZLabel                    matlab.ui.control.Label
        RotateYSpinner                  matlab.ui.control.Spinner
        RotateYLabel                    matlab.ui.control.Label
        RotateXSpinner                  matlab.ui.control.Spinner
        RotateXLabel                    matlab.ui.control.Label
        MaxEdgeSizemmSpinner            matlab.ui.control.Spinner
        MaxEdgeSizemmSpinnerLabel       matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        ProfilesTab                     matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        KerfEditField                   matlab.ui.control.NumericEditField
        KerfEditFieldLabel              matlab.ui.control.Label
        NoPointsEditField               matlab.ui.control.NumericEditField
        NoPointsEditFieldLabel          matlab.ui.control.Label
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes7                         matlab.ui.control.UIAxes
        BilletTab                       matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        ZmmEditField                    matlab.ui.control.NumericEditField
        ZmmEditFieldLabel               matlab.ui.control.Label
        YmmEditField                    matlab.ui.control.NumericEditField
        YmmEditFieldLabel               matlab.ui.control.Label
        InsertModelButton               matlab.ui.control.Button
        LoadBilletButton                matlab.ui.control.Button
        DepthmmEditField_2              matlab.ui.control.NumericEditField
        DepthmmEditField_2Label         matlab.ui.control.Label
        LengthmmEditField_2             matlab.ui.control.NumericEditField
        LengthmmEditField_2Label        matlab.ui.control.Label
        WidthmmEditField_2              matlab.ui.control.NumericEditField
        WidthmmEditField_2Label         matlab.ui.control.Label
        ZmmEditField_2                  matlab.ui.control.NumericEditField
        ZmmEditField_2Label             matlab.ui.control.Label
        YmmEditField_2                  matlab.ui.control.NumericEditField
        YmmEditField_2Label             matlab.ui.control.Label
        TranslateZSpinner_2             matlab.ui.control.Spinner
        TranslateZSpinner_2Label        matlab.ui.control.Label
        TranslateYSpinner_2             matlab.ui.control.Spinner
        TranslateYSpinner_2Label        matlab.ui.control.Label
        XmmEditField                    matlab.ui.control.NumericEditField
        XmmEditFieldLabel               matlab.ui.control.Label
        XmmEditField_2                  matlab.ui.control.NumericEditField
        XmmEditField_2Label             matlab.ui.control.Label
        TranslateXSpinner_2             matlab.ui.control.Spinner
        TranslateXSpinner_2Label        matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        MachineTab                      matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        TranslateZmmSpinner             matlab.ui.control.Spinner
        TranslateZmmSpinnerLabel        matlab.ui.control.Label
        TranslateYmmSpinner             matlab.ui.control.Spinner
        TranslateYmmSpinnerLabel        matlab.ui.control.Label
        InsertModelButton_2             matlab.ui.control.Button
        InsertBilletButton              matlab.ui.control.Button
        LoadCNCButton                   matlab.ui.control.Button
        TranslateXmmSpinner             matlab.ui.control.Spinner
        TranslateXmmSpinnerLabel        matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        CuttingTab                      matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        SelectStartPointSwitch          matlab.ui.control.Switch
        SelectStartPointSwitchLabel     matlab.ui.control.Label
        SelectEntryPointSwitch          matlab.ui.control.Switch
        SelectEntryPointSwitchLabel     matlab.ui.control.Label
        DirectionOfCutDropDown          matlab.ui.control.DropDown
        DirectionOfCutDropDownLabel     matlab.ui.control.Label
        FoamEntryDropDown               matlab.ui.control.DropDown
        FoamEntryDropDownLabel          matlab.ui.control.Label
        InsertProjectedProfilesButton   matlab.ui.control.Button
        UIAxes9                         matlab.ui.control.UIAxes
        UIAxes8                         matlab.ui.control.UIAxes
        CuttingVisTab                   matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        SimulateCutButton               matlab.ui.control.Button
        UIAxes10                        matlab.ui.control.UIAxes
        GcodeTab                        matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        Slider                          matlab.ui.control.Slider
        GenerateGcodeButton             matlab.ui.control.Button
        FeedRateEditField               matlab.ui.control.NumericEditField
        FeedRateEditFieldLabel          matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        FileNameLabel                   matlab.ui.control.Label
        SaveGcodeButton                 matlab.ui.control.Button
        RunGcodeButton                  matlab.ui.control.Button
        TextArea_5                      matlab.ui.control.TextArea
        Label_4                         matlab.ui.control.Label
        UIAxes11                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        stl = struct('rotAlpha', 0, 'rotGamma', 0, 'rotDelta', 0, 'rootPlane', 0, 'tipPlane', 0, 'transX', 0, 'transY', 0, 'transZ', 0);

        cnc = struct('boxW', 1400, 'boxShiftX', -200, 'boxL', 1000, 'boxShiftY', -175, 'boxD', 700, 'boxShiftZ', -140,          'bedW', 1000, 'bedShiftX', 0, 'bedL', 490, 'bedShiftY', 60, 'bedD', 0, 'bedShiftZ', -140,        'cutW', 1000, 'cutShiftX', 0, 'cutL', 550, 'cutShiftY', 0, 'cutD', 300, 'cutShiftZ', 0,      'xyHeadPlaneX', -100, 'zaHeadPlaneX', 1100);


        billet = struct('origin', [0, 0, 0], 'transX', 0, 'transY', 0, 'transZ', 0, 'length', 10, 'width', 10, 'depth', 10);

        gcode = struct('oldDirectionOfCut', 'CW');
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            
%           %%%%%%%%%%%%%%%%%%%%%%
            app.GreetingText.Value = ['---------------------------------------------------------------', newline, ...
                                      'G-CODE GENERATOR', newline, ...
                                      'HOT WIRE FOAM CUTTER CNC MACHINE', newline, ...                                       
                                      'Dept. Aerospace Engineering', newline, ...
                                      'University of Bristol', newline, ...
                                      '---------------------------------------------------------------', newline, newline, newline,newline,newline,newline,newline,newline,newline,newline,newline,newline,newline,newline,...
                                    'Contributors:', newline,...
                                    'Frederico Paulino         - f.paulino@bristol.ac.uk',newline,...
                                    'Matthew Richardson     - matthew.richardson@bristol.ac.uk'];
%                                     'GUI implementation derived from "gCodeGenv1_4.m" and "stlRead.m" scripts created by Terence Macquart and ...., respectively.'
            app.GreetingText.HorizontalAlignment = 'left';
            app.GreetingText.FontName = 'Arial';
            app.GreetingText.FontSize = 24;
            app.GreetingText.FontColor = 'g';
            app.GreetingText.BackgroundColor = 'k';
%           %%%%%%%%%%%%%%%%%%%%%%
            plot3(app.UIAxes, 0, 0, 0, 'ro', 'markersize', 1, 'MarkerFaceColor', 'r', 'MarkerEdgeColor','r');
            set(app.UIAxes, 'DataAspectRatio', [1 1 1]);
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.UIAxes.ZGrid = 'on';
            app.UIAxes.Box = 'on';
%           %%%%%%%%%%%%%%%%%%%%%%
            plot3(app.UIAxes2, 0, 0, 0, 'ro', 'markersize', 1, 'MarkerFaceColor', 'r', 'MarkerEdgeColor','r');
            set(get(app.UIAxes2, 'xlabel'), 'string', 'Width - X');
            set(get(app.UIAxes2, 'ylabel'), 'string', 'Length - Y');
            set(get(app.UIAxes2, 'zlabel'), 'string', 'Depth - Z');
            set(app.UIAxes2, 'DataAspectRatio', [1 1 1]);
            app.UIAxes2.XGrid = 'on';
            app.UIAxes2.YGrid = 'on';
            app.UIAxes2.ZGrid = 'on';
            app.UIAxes2.XLim = [app.cnc.boxShiftX, app.cnc.boxShiftX+app.cnc.boxW];
            app.UIAxes2.YLim = [app.cnc.boxShiftY, app.cnc.boxShiftY+app.cnc.boxL];
            app.UIAxes2.ZLim = [app.cnc.boxShiftZ, app.cnc.boxShiftZ+app.cnc.boxD];
            app.UIAxes2.Box = 'on';
%           %%%%%%%%%%%%%%%%%%%%%%
            plot3(app.UIAxes3, 0, 0, 0, 'ro', 'markersize', 1, 'MarkerFaceColor', 'r', 'MarkerEdgeColor','r');
            set(get(app.UIAxes3, 'xlabel'), 'string', 'Width - X');
            set(get(app.UIAxes3, 'ylabel'), 'string', 'Length - Y');
            set(get(app.UIAxes3, 'zlabel'), 'string', 'Depth - Z');
            set(app.UIAxes3, 'DataAspectRatio', [1 1 1]);
            set(app.UIAxes3, 'XLim', [0, app.billet.width]);
            set(app.UIAxes3, 'YLim', [0, app.billet.length]);
            set(app.UIAxes3, 'ZLim', [0, app.billet.depth]);
            app.UIAxes3.XGrid = 'on';
            app.UIAxes3.YGrid = 'on';
            app.UIAxes3.ZGrid = 'on';
            app.UIAxes3.Box = 'on';
            plot3(app.UIAxes4, 0, 0, 0, 'ro', 'markersize', 1, 'MarkerFaceColor', 'r', 'MarkerEdgeColor','r');
            set(get(app.UIAxes4, 'xlabel'), 'string', 'Width - X');
            set(get(app.UIAxes4, 'ylabel'), 'string', 'Length - Y');
            set(get(app.UIAxes4, 'zlabel'), 'string', 'Depth - Z');
            set(app.UIAxes4, 'DataAspectRatio', [1 1 1]);
            set(app.UIAxes4, 'XLim', [0, app.billet.width]);
            set(app.UIAxes4, 'YLim', [0, app.billet.length]);
            set(app.UIAxes4, 'ZLim', [0, app.billet.depth]);
            app.UIAxes4.XGrid = 'on';
            app.UIAxes4.YGrid = 'on';
            app.UIAxes4.ZGrid = 'on';
            app.UIAxes4.Box = 'on';
            plot3(app.UIAxes5, 0, 0, 0, 'ro', 'markersize', 1, 'MarkerFaceColor', 'r', 'MarkerEdgeColor','r');
            set(get(app.UIAxes5, 'xlabel'), 'string', 'Width - X');
            set(get(app.UIAxes5, 'ylabel'), 'string', 'Length - Y');
            set(get(app.UIAxes5, 'zlabel'), 'string', 'Depth - Z');
            set(app.UIAxes5, 'DataAspectRatio', [1 1 1]);
            set(app.UIAxes5, 'XLim', [0, app.billet.width]);
            set(app.UIAxes5, 'YLim', [0, app.billet.length]);
            set(app.UIAxes5, 'ZLim', [0, app.billet.depth]);
            app.UIAxes5.XGrid = 'on';
            app.UIAxes5.YGrid = 'on';
            app.UIAxes5.ZGrid = 'on';
            app.UIAxes5.Box = 'on';
%           %%%%%%%%%%%%%%%%%%%%%%
            set(get(app.UIAxes6, 'ylabel'), 'string', 'Y');
            set(get(app.UIAxes6, 'zlabel'), 'string', 'Z');
            set(app.UIAxes6, 'DataAspectRatio', [1 1 1]);
            app.UIAxes6.XGrid = 'on';
            app.UIAxes6.YGrid = 'on';
            app.UIAxes6.View = [90, 0];
            set(get(app.UIAxes7, 'ylabel'), 'string', 'Y');
            set(get(app.UIAxes7, 'zlabel'), 'string', 'Z');
            set(app.UIAxes7, 'DataAspectRatio', [1 1 1]);
            app.UIAxes7.XGrid = 'on';
            app.UIAxes7.YGrid = 'on';            
            app.UIAxes7.View = [90, 0];
%           %%%%%%%%%%%%%%%%%%%%%%
            set(get(app.UIAxes8, 'xlabel'), 'string', 'X');
            set(get(app.UIAxes8, 'ylabel'), 'string', 'Y');
            set(app.UIAxes8, 'DataAspectRatio', [1 1 1]);
            app.UIAxes8.XGrid = 'on';
            app.UIAxes8.YGrid = 'on';
            app.UIAxes8.View = [0, 90];
            set(get(app.UIAxes9, 'xlabel'), 'string', 'Z');
            set(get(app.UIAxes9, 'ylabel'), 'string', 'A');
            set(app.UIAxes9, 'DataAspectRatio', [1 1 1]);
            app.UIAxes9.XGrid = 'on';
            app.UIAxes9.YGrid = 'on';
            app.UIAxes9.View = [0, 90];
%           %%%%%%%%%%%%%%%%%%%%%%
            plot3(app.UIAxes10, 0, 0, 0, 'ro', 'markersize', 1, 'MarkerFaceColor', 'r', 'MarkerEdgeColor','r');
            set(get(app.UIAxes10, 'xlabel'), 'string', 'Width - X');
            set(get(app.UIAxes10, 'ylabel'), 'string', 'Length - Y');
            set(get(app.UIAxes10, 'zlabel'), 'string', 'Depth - Z');
            set(app.UIAxes10, 'DataAspectRatio', [1 1 1]);
            app.UIAxes10.XGrid = 'on';
            app.UIAxes10.YGrid = 'on';
            app.UIAxes10.ZGrid = 'on';
            app.UIAxes10.Box = 'on';
%           %%%%%%%%%%%%%%%%%%%%%%
            plot3(app.UIAxes11, 0, 0, 0, 'ro', 'markersize', 1, 'MarkerFaceColor', 'r', 'MarkerEdgeColor','r');
            set(get(app.UIAxes11, 'xlabel'), 'string', 'Width - X');
            set(get(app.UIAxes11, 'ylabel'), 'string', 'Length - Y');
            set(get(app.UIAxes11, 'zlabel'), 'string', 'Depth - Z');
            set(app.UIAxes11, 'DataAspectRatio', [1 1 1]);
            app.UIAxes11.XGrid = 'on';
            app.UIAxes11.YGrid = 'on';
            app.UIAxes11.ZGrid = 'on';
            app.UIAxes11.XLim = [app.cnc.boxShiftX, app.cnc.boxShiftX+app.cnc.boxW];
            app.UIAxes11.YLim = [app.cnc.boxShiftY, app.cnc.boxShiftY+app.cnc.boxL];
            app.UIAxes11.ZLim = [app.cnc.boxShiftZ, app.cnc.boxShiftZ+app.cnc.boxD];
            app.UIAxes11.Box = 'on';
%           %%%%%%%%%%%%%%%%%%%%%%
            app.GuideText.FontName = 'Arial';
            app.GuideText.FontSize = 20;
            app.GuideText.FontWeight = 'bold';
            app.GuideText.FontColor = 'w';
            app.GuideText.BackgroundColor = 'k';
            app.GuideText.HorizontalAlignment = 'left';
            app.GuideText.Value =  'Guidance';
%           %%%%%%%%%%%%%%%%%%%%%%
            app.WarningText.FontName = 'Arial';
            app.WarningText.FontSize = 20;
            app.WarningText.FontWeight = 'bold';
            app.WarningText.FontColor = 'y';
            app.WarningText.BackgroundColor = 'k';
            app.WarningText.HorizontalAlignment = 'left';
            app.WarningText.Value =  'Warnings';
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            switch selectedTab.Title
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %                 
                case 'Model'
                    app.WarningText.Value = '';
                    app.GuideText.Value = [ '1. Select the model .stl file.', newline,newline, ...
                                            '2. Choose the minimum and maximum edge sizes of the triangular mesh that discretizes the model surface.', newline, newline,...
                                            '3. Rotate the model to the desired position (remember: the hot wire stretches along the X-direction and the heads move in parallel to the YZ plane).', newline, newline,...
                                            '4. Decide the X-coordinates of the planes that define the LHS (root) and RHS (tip) cutting profiles.', newline, newline,...
                                            '5. From the drop down menu, choose the option that best describes your geometry, i.e. straight or tapered.', newline, newline,...
                                            '6. Generate the root and tip profiles to be projected on the XY and ZA planes of the machine heads.'];
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %                                 
                case 'Profiles'
                    if isfield(app.stl, 'root') == 0 || isfield(app.stl, 'tip') == 0
                        app.TabGroup.SelectedTab = app.TabGroup.Children(2);
                        app.WarningText.Value = 'Please, complete all the steps in the Model Tab.';
                    else
                        modelW = max(app.stl.tip.vertices(:,1))-min(app.stl.root.vertices(:,1));
                        modelL = max(max(app.stl.root.vertices(:,2)),max(app.stl.tip.vertices(:,2)))-min(min(app.stl.root.vertices(:,2)),min(app.stl.tip.vertices(:,2)));
                        modelD = max(max(app.stl.root.vertices(:,3)),max(app.stl.tip.vertices(:,3)))-min(min(app.stl.root.vertices(:,3)),min(app.stl.tip.vertices(:,3)));
                        
                        if modelW > app.cnc.cutW || modelL > app.cnc.cutL || modelD > app.cnc.cutD
                            app.TabGroup.SelectedTab = app.TabGroup.Children(2);
                            app.WarningText.Value = ['Chosen model and orientation result in cutting profiles larger than the machine dimensions!',newline,newline,...
                                                    'Please, check the configuration in the Model Tab.'];
                        else
                            app.WarningText.Value = '';
                            app.GuideText.Value = [ '1. Confirm the root and tip cutting profiles.', newline,newline, ...
                                                    '2. Using the sliders, choose how compact the boundary that envelops the desired profiles should be, 0 being the least (convex hull) and 1 being the most compact.', newline, newline,...
                                                    '3. Choose the number of points that will compose the projected cutting profiles in the final G-code.', newline, newline,...
                                                    '4. Choose the kerf (i.e. offset or allowance) to be applied on the profiles to compensate for the hot wire thickness and temperature, and consequent loss of foam material during the cutting process.'];
                        end
                    end
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %               
                case 'Billet'
                    if isfield(app.stl, 'root') == 0 || isfield(app.stl, 'tip') == 0
                        app.TabGroup.SelectedTab = app.TabGroup.Children(2);
                        app.WarningText.Value = 'Please, complete all the steps in the Model Tab.';
                    else
                        modelW = max(app.stl.tip.vertices(:,1))-min(app.stl.root.vertices(:,1));
                        modelL = max(max(app.stl.root.vertices(:,2)),max(app.stl.tip.vertices(:,2)))-min(min(app.stl.root.vertices(:,2)),min(app.stl.tip.vertices(:,2)));
                        modelD = max(max(app.stl.root.vertices(:,3)),max(app.stl.tip.vertices(:,3)))-min(min(app.stl.root.vertices(:,3)),min(app.stl.tip.vertices(:,3)));
                        
                        if modelW > app.cnc.cutW || modelL > app.cnc.cutL || modelD > app.cnc.cutD
                            app.TabGroup.SelectedTab = app.TabGroup.Children(2);
                            app.WarningText.Value = ['Chosen model and orientation result in cutting profiles larger than the machine dimensions!',newline,newline,...
                                                    'Please, check the configuration in the Model Tab.'];
                        else
                            if app.KerfEditField.Value == 0
                                app.TabGroup.SelectedTab = app.TabGroup.Children(3);
                                app.WarningText.Value = ['The cutting profiles do not include a kerf!',newline,newline,...
                                                        'Please, reconsider adding a kerf related to the hot wire thickness and temperature (i.e. current and voltage). Otherwise, just enter a very small value e.g. 10e-6 as your kerf.'];
                            else
                                if isfield(app.stl, 'rootProfile') == 0 || isfield(app.stl, 'tipProfile') == 0
                                    app.TabGroup.SelectedTab = app.TabGroup.Children(3);
                                    app.WarningText.Value = 'Please, complete all the steps in the Profiles Tab.';
                                else
                                    app.WarningText.Value = '';
                                    app.GuideText.Value = [ '1. Choose the dimensions (width, length and depth - global X, Y and Z coordinate directions, respectively) of the foam billet.', newline,newline, ...
                                                            '2. Load the billet.', newline, newline,...
                                                            '3. Insert the model within the billet.', newline, newline,...
                                                            '4. Using the spinners, translate the model to a suitable position within the billet.'];
                                end
                            end
                        end
                    end
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %               
                case 'Machine'
                    if isfield(app.stl, 'root') == 0 || isfield(app.stl, 'tip') == 0
                        app.TabGroup.SelectedTab = app.TabGroup.Children(2);
                        app.WarningText.Value = 'Please, complete all the steps in the Model Tab.';
                    else
                        modelW = max(app.stl.tip.vertices(:,1))-min(app.stl.root.vertices(:,1));
                        modelL = max(max(app.stl.root.vertices(:,2)),max(app.stl.tip.vertices(:,2)))-min(min(app.stl.root.vertices(:,2)),min(app.stl.tip.vertices(:,2)));
                        modelD = max(max(app.stl.root.vertices(:,3)),max(app.stl.tip.vertices(:,3)))-min(min(app.stl.root.vertices(:,3)),min(app.stl.tip.vertices(:,3)));
                        
                        if modelW > app.cnc.cutW || modelL > app.cnc.cutL || modelD > app.cnc.cutD
                            app.TabGroup.SelectedTab = app.TabGroup.Children(2);
                            app.WarningText.Value = ['Chosen model and orientation result in cutting profiles larger than the machine dimensions!',newline,newline,...
                                                    'Please, check the configuration in the Model Tab.'];
                        else
                            if app.KerfEditField.Value == 0
                                app.TabGroup.SelectedTab = app.TabGroup.Children(3);
                                app.WarningText.Value = ['The cutting profiles do not include a kerf!',newline,newline,...
                                                        'Please, reconsider adding a kerf related to the hot wire thickness and temperature (i.e. current and voltage). Otherwise, just enter a very small value e.g. 10e-6 as your kerf.'];
                            else
                                if isfield(app.stl, 'rootProfile') == 0 || isfield(app.stl, 'tipProfile') == 0
                                    app.TabGroup.SelectedTab = app.TabGroup.Children(3);
                                    app.WarningText.Value = 'Please, complete all the steps in the Profiles Tab.';
                                else
                                    minX = min(min(app.stl.offsetRootProfile(:,1)),min(app.stl.offsetTipProfile(:,1)));
                                    minY = min(min(app.stl.offsetRootProfile(:,2)),min(app.stl.offsetTipProfile(:,2)));
                                    minZ = min(min(app.stl.offsetRootProfile(:,3)),min(app.stl.offsetTipProfile(:,3)));
                                    maxX = max(max(app.stl.offsetRootProfile(:,1)),max(app.stl.offsetTipProfile(:,1)));
                                    maxY = max(max(app.stl.offsetRootProfile(:,2)),max(app.stl.offsetTipProfile(:,2)));
                                    maxZ = max(max(app.stl.offsetRootProfile(:,3)),max(app.stl.offsetTipProfile(:,3)));

                                    if minX < app.billet.origin(1) || maxX > (app.billet.origin(1) + app.billet.width) || minY < app.billet.origin(2) || maxY > (app.billet.origin(2) + app.billet.length) || minZ < app.billet.origin(3) || maxZ > (app.billet.origin(3) + app.billet.depth)
                                        app.TabGroup.SelectedTab = app.TabGroup.Children(4);
                                        app.WarningText.Value = [   'Chosen cutting profiles and respective kerf result in a cutting model larger than the chosen billet dimensions!',newline,newline,...
                                                                    'Please, check the configurations either in the Profiles or Billet Tabs.'];   
                                    else
                                        app.WarningText.Value = '';
                                        app.GuideText.Value = [ '1. Load the CNC.', newline,newline, ...
                                                                '2. Insert the Billet within the CNC machine.', newline, newline,...
                                                                '3. Insert the Model within the billet.', newline, newline,...
                                                                '4. Using the spinners, translate the billet and cutting model to a suitable position in the CNC machine (above the bed).'];
                                    end
                                end
                            end
                        end
                    end
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %    
                case 'Cutting'
                    if isfield(app.stl, 'root') == 0 || isfield(app.stl, 'tip') == 0
                        app.TabGroup.SelectedTab = app.TabGroup.Children(2);
                        app.WarningText.Value = 'Please, complete all the steps in the Model Tab.';
                    else
                        modelW = max(app.stl.tip.vertices(:,1))-min(app.stl.root.vertices(:,1));
                        modelL = max(max(app.stl.root.vertices(:,2)),max(app.stl.tip.vertices(:,2)))-min(min(app.stl.root.vertices(:,2)),min(app.stl.tip.vertices(:,2)));
                        modelD = max(max(app.stl.root.vertices(:,3)),max(app.stl.tip.vertices(:,3)))-min(min(app.stl.root.vertices(:,3)),min(app.stl.tip.vertices(:,3)));
                        
                        if modelW > app.cnc.cutW || modelL > app.cnc.cutL || modelD > app.cnc.cutD
                            app.TabGroup.SelectedTab = app.TabGroup.Children(2);
                            app.WarningText.Value = ['Chosen model and orientation result in cutting profiles larger than the machine dimensions!',newline,newline,...
                                                    'Please, check the configuration in the Model Tab.'];
                        else
                            if app.KerfEditField.Value == 0
                                app.TabGroup.SelectedTab = app.TabGroup.Children(3);
                                app.WarningText.Value = ['The cutting profiles do not include a kerf!',newline,newline,...
                                                        'Please, reconsider adding a kerf related to the hot wire thickness and temperature (i.e. current and voltage). Otherwise, just enter a very small value e.g. 10e-6 as your kerf.'];
                            else
                                if isfield(app.stl, 'rootProfile') == 0 || isfield(app.stl, 'tipProfile') == 0
                                    app.TabGroup.SelectedTab = app.TabGroup.Children(3);
                                    app.WarningText.Value = 'Please, complete all the steps in the Profiles Tab.';
                                else
                                    minX = min(min(app.stl.offsetRootProfile(:,1)),min(app.stl.offsetTipProfile(:,1)));
                                    minY = min(min(app.stl.offsetRootProfile(:,2)),min(app.stl.offsetTipProfile(:,2)));
                                    minZ = min(min(app.stl.offsetRootProfile(:,3)),min(app.stl.offsetTipProfile(:,3)));
                                    maxX = max(max(app.stl.offsetRootProfile(:,1)),max(app.stl.offsetTipProfile(:,1)));
                                    maxY = max(max(app.stl.offsetRootProfile(:,2)),max(app.stl.offsetTipProfile(:,2)));
                                    maxZ = max(max(app.stl.offsetRootProfile(:,3)),max(app.stl.offsetTipProfile(:,3)));

                                    if minX < app.billet.origin(1) || maxX > (app.billet.origin(1) + app.billet.width) || minY < app.billet.origin(2) || maxY > (app.billet.origin(2) + app.billet.length) || minZ < app.billet.origin(3) || maxZ > (app.billet.origin(3) + app.billet.depth)
                                        app.TabGroup.SelectedTab = app.TabGroup.Children(4);
                                        app.WarningText.Value = [   'Chosen cutting profiles and respective kerf result in a cutting model larger the chosen billet dimensions!',newline,newline,...
                                                                    'Please, check the configurations either in the Profiles or Billet Tabs.'];   
                                    else
                                        if app.billet.origin(1) < 0 || (app.billet.origin(1) + app.billet.width) > app.cnc.cutW || app.billet.origin(2) < 60 || (app.billet.origin(2) + app.billet.length) > 60 + app.cnc.cutL || app.billet.origin(3) < 0 || (app.billet.origin(3) + app.billet.depth) > app.cnc.cutD
                                            app.TabGroup.SelectedTab = app.TabGroup.Children(5);
                                            app.WarningText.Value = [   'Chosen billet dimensions and/or position are larger and/or outside the machine dimensions!',newline,newline,...
                                                                        'Please, check the configurations either in the Billet or Machine Tabs (e.g. translate the billet by 60 mm in the y-coordinate to fit it above the bed).'];       
                                        else
                                            if isempty(app.stl.projRootProfileIdxOut) == 0 || isempty(app.stl.projTipProfileIdxOut) == 0
                                                app.TabGroup.SelectedTab = app.TabGroup.Children(5);
                                                app.WarningText.Value = [   'Chosen billet and cutting model positions result in projected cutting profiles outside the machine dimensions!',newline,newline,...
                                                                            'Please, check the configurations in the Machine Tab.'];       
                                            else
                                                app.WarningText.Value = '';
                                                app.GuideText.Value = [ '1. Insert the root and tip profiles projected into the planes (XY and ZA) of the machine heads.', newline,newline, ...
                                                                        '2. From the drop down menu, decide on a direction of cut: clockwise or counterclockwise around the cutting profiles.', newline, newline,...
                                                                        '3. Switch to select the cutting start point.', newline, newline,...
                                                                        '4. Using the mouse pointer, left-click and select on either of the profile plots a point for the hot wire to start cutting the profiles.', newline, newline,...
                                                                        '5. From the drop down menu, decide whether the hot wire will enter the billet through or avoiding its front face.', newline, newline,...
                                                                        '6. Switch to select the billet entry point.', newline, newline,...
                                                                        '7. Using the mouse pointer, left-click and select on both of the profile plots points for the hot wire to enter the foam billet.'];
%                                                                         '6. *************** From the drop down menu, decide whether the default zero (bottom left corner) of the machine will be used as the start/end points of cutting or if a new zero will be manually adjusted.',newline,newline,...                                                                        
                                            end
                                        end
                                            
                                    end
                                end
                            end
                        end
                    end
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %    
                case 'Cutting Vis'
                    if isfield(app.stl, 'root') == 0 || isfield(app.stl, 'tip') == 0
                        app.TabGroup.SelectedTab = app.TabGroup.Children(2);
                        app.WarningText.Value = 'Please, complete all the steps in the Model Tab.';
                    else
                        modelW = max(app.stl.tip.vertices(:,1))-min(app.stl.root.vertices(:,1));
                        modelL = max(max(app.stl.root.vertices(:,2)),max(app.stl.tip.vertices(:,2)))-min(min(app.stl.root.vertices(:,2)),min(app.stl.tip.vertices(:,2)));
                        modelD = max(max(app.stl.root.vertices(:,3)),max(app.stl.tip.vertices(:,3)))-min(min(app.stl.root.vertices(:,3)),min(app.stl.tip.vertices(:,3)));
                        
                        if modelW > app.cnc.cutW || modelL > app.cnc.cutL || modelD > app.cnc.cutD
                            app.TabGroup.SelectedTab = app.TabGroup.Children(2);
                            app.WarningText.Value = ['Chosen model and orientation result in cutting profiles larger than the machine dimensions!',newline,newline,...
                                                    'Please, check the configuration in the Model Tab.'];
                        else
                            if app.KerfEditField.Value == 0
                                app.TabGroup.SelectedTab = app.TabGroup.Children(3);
                                app.WarningText.Value = ['The cutting profiles do not include a kerf!',newline,newline,...
                                                        'Please, reconsider adding a kerf related to the hot wire thickness and temperature (i.e. current and voltage). Otherwise, just enter a very small value e.g. 10e-6 as your kerf.'];
                            else
                                if isfield(app.stl, 'rootProfile') == 0 || isfield(app.stl, 'tipProfile') == 0
                                    app.TabGroup.SelectedTab = app.TabGroup.Children(3);
                                    app.WarningText.Value = 'Please, complete all the steps in the Profiles Tab.';
                                else
                                    minX = min(min(app.stl.offsetRootProfile(:,1)),min(app.stl.offsetTipProfile(:,1)));
                                    minY = min(min(app.stl.offsetRootProfile(:,2)),min(app.stl.offsetTipProfile(:,2)));
                                    minZ = min(min(app.stl.offsetRootProfile(:,3)),min(app.stl.offsetTipProfile(:,3)));
                                    maxX = max(max(app.stl.offsetRootProfile(:,1)),max(app.stl.offsetTipProfile(:,1)));
                                    maxY = max(max(app.stl.offsetRootProfile(:,2)),max(app.stl.offsetTipProfile(:,2)));
                                    maxZ = max(max(app.stl.offsetRootProfile(:,3)),max(app.stl.offsetTipProfile(:,3)));

                                    if minX < app.billet.origin(1) || maxX > (app.billet.origin(1) + app.billet.width) || minY < app.billet.origin(2) || maxY > (app.billet.origin(2) + app.billet.length) || minZ < app.billet.origin(3) || maxZ > (app.billet.origin(3) + app.billet.depth)
                                        app.TabGroup.SelectedTab = app.TabGroup.Children(4);
                                        app.WarningText.Value = [   'Chosen cutting profiles and respective kerf result in a cutting model larger the chosen billet dimensions!',newline,newline,...
                                                                    'Please, check the configurations either in the Profiles or Billet Tabs.'];   
                                    else
                                        if app.billet.origin(1) < 0 || (app.billet.origin(1) + app.billet.width) > app.cnc.cutW || app.billet.origin(2) < 60 || (app.billet.origin(2) + app.billet.length) > 60 + app.cnc.cutL || app.billet.origin(3) < 0 || (app.billet.origin(3) + app.billet.depth) > app.cnc.cutD
                                            app.TabGroup.SelectedTab = app.TabGroup.Children(5);
                                            app.WarningText.Value = [   'Chosen billet dimensions and/or position are larger and/or outside the machine dimensions!',newline,newline,...
                                                                        'Please, check the configurations either in the Billet or Machine Tabs (e.g. translate the billet by 60 mm in the y-coordinate to fit it above the bed).'];       
                                        else
                                            if isempty(app.stl.projRootProfileIdxOut) == 0 || isempty(app.stl.projTipProfileIdxOut) == 0
                                                app.TabGroup.SelectedTab = app.TabGroup.Children(5);
                                                app.WarningText.Value = [   'Chosen billet and cutting model positions result in projected cutting profiles outside the machine dimensions!',newline,newline,...
                                                                            'Please, check the configurations in the Machine Tab.'];       
                                            else
                                                if isfield(app.stl, 'cutEntryRootProfile') == 0 || isfield(app.stl, 'cutEntryTipProfile') == 0
                                                    app.TabGroup.SelectedTab = app.TabGroup.Children(6);
                                                    app.WarningText.Value = [   'A foam entry point (either at root or at tip) is missing in one of your cutting profiles!',newline,newline,...
                                                                                'Please, check the configurations in the Cutting Tab.'];
                                                else
                                                    app.WarningText.Value = '';
                                                    app.GuideText.Value = 'Simulate and assess the cut.';
                                                end
                                            end
                                        end 
                                    end
                                end
                            end
                        end
                    end
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %    
                case 'G-code'
                    app.WarningText.Value = '';
                    app.GuideText.Value = ['1. Choose the desired feed rate (mm/min).', newline, newline,...
                        '2. Choose the G-code .tap file name.', newline, newline,...
                        '3. Generate the G-code.', newline, newline,...
                        '4. Run the G-code either via button or slider and assess.', newline, newline,...
                        '5. Save the G-code in the desired desired folder location.'];

% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %    
            end

        end

        % Button pushed function: LoadSTLButton
        function LoadSTLButtonPushed(app, event)
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
%            GET .STL FILE AND PATH TO IT
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
%             app.stl.file = 'straight_wing.stl';
%             app.stl.path = pwd;
            [app.stl.file, app.stl.path] = uigetfile('*.stl');

            cwd = pwd;
            cd(app.stl.path);
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
%           GENERATE SURFACE MESH FROM .STL
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
            numberOfPDEs = 1;
            app.stl.model = createpde(numberOfPDEs);
            app.stl.geom = importGeometry(app.stl.model,app.stl.file);
            app.stl.mesh = generateMesh(app.stl.model, 'GeometricOrder','linear');
            
            app.stl.points  = app.stl.mesh.Nodes;
            app.stl.tetra     = [app.stl.mesh.Elements; ones(1,size(app.stl.mesh.Elements,2))];

            cd(cwd);

            app.stl.maxEleSize          = app.stl.mesh.MaxElementSize;
            app.stl.minEleSize          = app.stl.mesh.MinElementSize;
            
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %              
%             PLOT SURFACE MESH
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
            [app.stl.faces, app.stl.vertices] = tetBoundaryFacets(app.stl.points,app.stl.tetra(1:4,:));
            set(app.UIAxes.Parent.Parent.Parent.Parent.Parent.Parent, 'renderer', 'opengl');
            trisurf(app.stl.faces, app.stl.vertices(:,1),app.stl.vertices(:,2),app.stl.vertices(:,3),'FaceColor','g', 'EdgeColor','k','FaceAlpha', 1, 'Clipping','off', 'Parent', app.UIAxes);
            
            
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
%             SET MODIFICATIONS IN THE MODEL TAB ACORDING TO SURFACE MESH
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
            app.MinEdgeSizemmSpinner.Value = app.stl.minEleSize;
            app.MaxEdgeSizemmSpinner.Value = app.stl.maxEleSize;
            app.RotateXSpinner.Value = 0;
            app.RotateYSpinner.Value = 0;
            app.RotateZSpinner.Value = 0;

            app.RootPlaneXcoordmmEditField.Value    = ceil(min(app.stl.points(1,:)))+0.001;
            app.TipPlaneXcoordmmEditField.Value     = floor(max(app.stl.points(1,:)))-0.001;
        end

        % Value changed function: MinEdgeSizemmSpinner
        function MinEdgeSizemmSpinnerValueChanged(app, event)
            if isfield(app.stl, 'model') == 1
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
%                 REGENERATE SURFACE MESH FROM .STL USING MINIMUM EDGE SIZE RESTRICTION
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
                app.stl.mesh                = generateMesh(app.stl.model, 'Hmin', app.MinEdgeSizemmSpinner.Value);
                app.stl.maxEleSize          = app.stl.mesh.MaxElementSize;
                app.stl.minEleSize          = app.stl.mesh.MinElementSize;

% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
%                 REPLOT SURFACE MESH
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
                app.stl.points              = app.stl.mesh.Nodes;
                app.stl.tetra               = [app.stl.mesh.Elements; ones(1,size(app.stl.mesh.Elements,2))];
                [app.stl.faces, app.stl.vertices] = tetBoundaryFacets(app.stl.points,app.stl.tetra(1:4,:));
                cla(app.UIAxes);
                trisurf(app.stl.faces, app.stl.vertices(:,1),app.stl.vertices(:,2),app.stl.vertices(:,3),'FaceColor','g', 'EdgeColor','k','FaceAlpha', 1, 'Clipping','off', 'Parent', app.UIAxes);
                
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
%              RESET MODIFICATIONS IN THE MODEL TAB ACORDING TO SURFACE MESH
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
                app.RotateXSpinner.Value = 0;
                app.stl.rotAlpha = 0;
                app.RotateYSpinner.Value = 0;
                app.stl.rotGamma = 0;
                app.RotateZSpinner.Value = 0;
                app.stl.rotDelta = 0;
            end
        end

        % Value changed function: MaxEdgeSizemmSpinner
        function MaxEdgeSizemmSpinnerValueChanged(app, event)
            if isfield(app.stl, 'model') == 1
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
%                 REGENERATE SURFACE MESH FROM .STL USING MAXIMUM EDGE SIZE RESTRICTION
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
                app.stl.mesh        = generateMesh(app.stl.model, 'Hmax', app.MaxEdgeSizemmSpinner.Value);
                app.stl.minEleSize  = app.stl.mesh.MinElementSize;
                app.stl.maxEleSize  = app.stl.mesh.MaxElementSize;
                
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
%                 REPLOT SURFACE MESH
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %                
                app.stl.points  = app.stl.mesh.Nodes;
                app.stl.tetra     = [app.stl.mesh.Elements; ones(1,size(app.stl.mesh.Elements,2))];
                [app.stl.faces, app.stl.vertices] = tetBoundaryFacets(app.stl.points,app.stl.tetra(1:4,:));
                cla(app.UIAxes);
                trisurf(app.stl.faces, app.stl.vertices(:,1),app.stl.vertices(:,2),app.stl.vertices(:,3),'FaceColor','g', 'EdgeColor','k','FaceAlpha', 1, 'Clipping','off', 'Parent', app.UIAxes);

% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
%              RESET MODIFICATIONS IN THE MODEL TAB ACORDING TO SURFACE MESH
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
                app.RotateXSpinner.Value = 0;
                app.stl.rotAlpha = 0;
                app.RotateYSpinner.Value = 0;
                app.stl.rotGamma = 0;
                app.RotateZSpinner.Value = 0;
                app.stl.rotDelta = 0;
            end
        end

        % Value changed function: RotateXSpinner
        function RotateXSpinnerValueChanged(app, event)
            if isfield(app.stl, 'points')
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
%             ROTATE SURFACE MESH AROUND X-COORDINATE AXIS
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
                alpha = app.RotateXSpinner.Value;
    
                rotX            = [1, 0, 0; 0, cosd(-(alpha-app.stl.rotAlpha)), -sind(-(alpha-app.stl.rotAlpha)); 0, sind(-(alpha-app.stl.rotAlpha)), cosd(-(alpha-app.stl.rotAlpha))];
                app.stl.points  = (app.stl.points'*rotX)';

% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
%                 REPLOT SURFACE MESH
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %               
                [app.stl.faces, app.stl.vertices]      = tetBoundaryFacets(app.stl.points,app.stl.tetra(1:4,:));
                cla(app.UIAxes);
                trisurf(app.stl.faces, app.stl.vertices(:,1),app.stl.vertices(:,2),app.stl.vertices(:,3),'FaceColor','g', 'EdgeColor','k','FaceAlpha', 1, 'Clipping','off', 'Parent', app.UIAxes);
    
                app.stl.rotAlpha = alpha;

% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
%              RESET MODIFICATIONS IN THE MODEL TAB ACORDING TO SURFACE MESH
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %             
                app.RootPlaneXcoordmmEditField.Value    = ceil(min(app.stl.points(1,:)))+0.001;
                app.TipPlaneXcoordmmEditField.Value     = floor(max(app.stl.points(1,:)))-0.001;
            end
        end

        % Value changed function: RotateYSpinner
        function RotateYSpinnerValueChanged(app, event)
            if isfield(app.stl, 'points')
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
%             ROTATE SURFACE MESH AROUND Y-COORDINATE AXIS
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %             
                gamma = app.RotateYSpinner.Value;
    
                rotY = [cosd(-(gamma-app.stl.rotGamma)), 0, sind(-(gamma-app.stl.rotGamma)); 0, 1, 0; -sind(-(gamma-app.stl.rotGamma)), 0, cosd(-(gamma-app.stl.rotGamma))];
                app.stl.points  = (app.stl.points'*rotY)';

% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
%                 REPLOT SURFACE MESH
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %                 
                [app.stl.faces, app.stl.vertices]      = tetBoundaryFacets(app.stl.points,app.stl.tetra(1:4,:));
                cla(app.UIAxes);
                trisurf(app.stl.faces, app.stl.vertices(:,1),app.stl.vertices(:,2),app.stl.vertices(:,3),'FaceColor','g', 'EdgeColor','k','FaceAlpha', 1, 'Clipping','off', 'Parent', app.UIAxes);
    
                app.stl.rotGamma = gamma;     

% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
%              RESET MODIFICATIONS IN THE MODEL TAB ACORDING TO SURFACE MESH
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %        
                app.RootPlaneXcoordmmEditField.Value    = ceil(min(app.stl.points(1,:)))+0.001;
                app.TipPlaneXcoordmmEditField.Value     = floor(max(app.stl.points(1,:)))-0.001;
            end
        end

        % Value changed function: RotateZSpinner
        function RotateZSpinnerValueChanged(app, event)
            if isfield(app.stl, 'points')
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
%             ROTATE SURFACE MESH AROUND Z-COORDINATE AXIS
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %                  
                delta = app.RotateZSpinner.Value;
              
                rotZ = [cosd(-(delta-app.stl.rotDelta)), -sind(-(delta-app.stl.rotDelta)), 0; sind(-(delta-app.stl.rotDelta)), cosd(-(delta-app.stl.rotDelta)), 0; 0, 0, 1];
                app.stl.points  = (app.stl.points'*rotZ)';

% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
%                 REPLOT SURFACE MESH
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %                             
                [app.stl.faces, app.stl.vertices]      = tetBoundaryFacets(app.stl.points,app.stl.tetra(1:4,:));
                cla(app.UIAxes);
                trisurf(app.stl.faces, app.stl.vertices(:,1),app.stl.vertices(:,2),app.stl.vertices(:,3),'FaceColor','g', 'EdgeColor','k','FaceAlpha', 1, 'Clipping','off', 'Parent', app.UIAxes);
    
                app.stl.rotDelta = delta; 

% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
%              RESET MODIFICATIONS IN THE MODEL TAB ACORDING TO SURFACE MESH
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %                
                app.RootPlaneXcoordmmEditField.Value    = ceil(min(app.stl.points(1,:)))+0.001;
                app.TipPlaneXcoordmmEditField.Value     = floor(max(app.stl.points(1,:)))-0.001;
            end
        end

        % Value changed function: RootPlaneXcoordmmEditField
        function RootPlaneXcoordmmEditFieldValueChanged(app, event)
            cla(app.UIAxes);
            trisurf(app.stl.faces, app.stl.vertices(:,1),app.stl.vertices(:,2),app.stl.vertices(:,3),'FaceColor','g', 'EdgeColor','k','FaceAlpha', 1, 'Clipping','off', 'Parent', app.UIAxes);
        end

        % Value changed function: TipPlaneXcoordmmEditField
        function TipPlaneXcoordmmEditFieldValueChanged(app, event)
            cla(app.UIAxes);
            trisurf(app.stl.faces, app.stl.vertices(:,1),app.stl.vertices(:,2),app.stl.vertices(:,3),'FaceColor','g', 'EdgeColor','k','FaceAlpha', 1, 'Clipping','off', 'Parent', app.UIAxes);
        end

        % Button pushed function: GenerateProfilesButton
        function GenerateProfilesButtonPushed(app, event)
            app.NoPointsEditField.Value = 0;
            if isfield(app.stl, 'points')
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
%              CREATE ROOT AND TIP PLANES
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %  
                xRoot   = app.RootPlaneXcoordmmEditField.Value;
                xTip    = app.TipPlaneXcoordmmEditField.Value;
                margin  = 100;
                yMin    = min(app.stl.points(2,:)) - margin;
                yMax    = max(app.stl.points(2,:)) + margin;
                zMin    = min(app.stl.points(3,:)) - margin;
                zMax    = max(app.stl.points(3,:)) + margin;
    % % % % % % % % % %             
                rootPlane.vertices = [  xRoot, yMin, zMin;
                                        xRoot, yMin, zMax;
                                        xRoot, yMax, zMax;
                                        xRoot, yMax, zMin];
                rootPlane.faces    = [  1, 2, 3;
                                        1, 3, 4];
    
    % % % % % % % % % % 
                tipPlane.vertices = [   xTip, yMin, zMin;
                                        xTip, yMin, zMax;
                                        xTip, yMax, zMax;
                                        xTip, yMax, zMin];
                tipPlane.faces    = [  1, 2, 3;
                                        1, 3, 4];
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
%              INTERSECT THE ROOT PLANE WITH THE .STL SURFACE MESH
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %                
                [~, app.stl.root] = SurfaceIntersection(rootPlane, app.stl);
            
                % removing "1D" edges - duplicate vertices
                idx = find(app.stl.root.edges(:,1) == app.stl.root.edges(:,2));
                app.stl.root.edges(idx,:) = [];

                % ordering the profile vertices according to the respective edges
                tmp         = zeros(size(app.stl.root.edges));
                tmp(1,:)    = app.stl.root.edges(1,:);
                for i = 2:1:length(app.stl.root.edges)
                    idx = find(app.stl.root.edges(:,1) == tmp(i-1,2));
                    tmp(i,:) = app.stl.root.edges(idx,:);
                end
                app.stl.root.edges      = tmp;
                app.stl.root.vertices   = app.stl.root.vertices(tmp(:,1), :);

% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
%              COPY THE ROOT PROFILE OR INTERSECT THE TIP PLANE WITH THE .STL SURFACE MESH
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %    
                if strcmp(app.GeometryDropDown.Value, 'Straight') == 1
                    app.stl.tip                 = app.stl.root;
                    app.stl.tip.vertices(:,1)   = xTip;
                elseif strcmp(app.GeometryDropDown.Value, 'Tapered') == 1
                    [~, app.stl.tip] = SurfaceIntersection(tipPlane, app.stl);

                    % removing "1D" edges - duplicate vertices
                    idx = find(app.stl.tip.edges(:,1) == app.stl.tip.edges(:,2));
                    app.stl.tip.edges(idx,:) = [];

                    % ordering the profile vertices according to the respective edges
                    tmp         = zeros(size(app.stl.tip.edges));
                    tmp(1,:)    = app.stl.tip.edges(1,:);
                    for i = 2:1:length(app.stl.tip.edges)
                        idx = find(app.stl.tip.edges(:,1) == tmp(i-1,2));
                        tmp(i,:) = app.stl.tip.edges(idx,:);
                    end
                    app.stl.tip.edges      = tmp;
                    app.stl.tip.vertices   = app.stl.tip.vertices(tmp(:,1), :);
                end
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
%              PLOT THE ROOT AND TIP PLANES
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %                
                hold(app.UIAxes, 'on');
                p1 = patch(app.UIAxes, rootPlane.vertices(:,1), rootPlane.vertices(:,2), rootPlane.vertices(:,3), 'r');
                p1.FaceVertexAlphaData  = 0.05;
                p1.FaceAlpha            = 'flat' ;
                p2 = patch(app.UIAxes, tipPlane.vertices(:,1), tipPlane.vertices(:,2), tipPlane.vertices(:,3), 'b');
                p2.FaceVertexAlphaData  = 0.05;
                p2.FaceAlpha            = 'flat' ;
                

% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
%              PLOT THE INTERSECTION PROFILES
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %
                plot3(app.stl.root.vertices(:,1),app.stl.root.vertices(:,2),app.stl.root.vertices(:,3),'ro', 'markerfacecolor', 'r', 'markeredgecolor', 'r', 'Parent',app.UIAxes);
                plot3(app.stl.root.vertices(:,1),app.stl.root.vertices(:,2),app.stl.root.vertices(:,3),'ro', 'markerfacecolor', 'r', 'markeredgecolor', 'r', 'Parent',app.UIAxes6);
                app.UIAxes6.View = [90, 0];
                plot3(app.stl.tip.vertices(:,1),app.stl.tip.vertices(:,2),app.stl.tip.vertices(:,3),'bo', 'markerfacecolor', 'b', 'markeredgecolor', 'b', 'Parent',app.UIAxes);
                plot3(app.stl.tip.vertices(:,1),app.stl.tip.vertices(:,2),app.stl.tip.vertices(:,3),'bo', 'markerfacecolor', 'b', 'markeredgecolor', 'b', 'Parent',app.UIAxes7);
                app.UIAxes7.View = [90, 0];

                hold(app.UIAxes, 'off');
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
%              SET MODIFICATION IN THE BILLET TAB ACCORDING TO PROFILES
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %
                minX = min(min(app.stl.tip.vertices(:,1)),min(app.stl.root.vertices(:,1)));
                minY = min(min(app.stl.tip.vertices(:,2)),min(app.stl.root.vertices(:,2)));
                minZ = min(min(app.stl.tip.vertices(:,3)),min(app.stl.root.vertices(:,3)));
                maxX = max(max(app.stl.tip.vertices(:,1)),max(app.stl.root.vertices(:,1)));
                maxY = max(max(app.stl.tip.vertices(:,2)),max(app.stl.root.vertices(:,2)));
                maxZ = max(max(app.stl.tip.vertices(:,3)),max(app.stl.root.vertices(:,3)));

                app.WidthmmEditField_2.Value = ceil(maxX - minX);
                app.billet.width = app.WidthmmEditField_2.Value;
                app.LengthmmEditField_2.Value = ceil(maxY - minY);
                app.billet.length = app.LengthmmEditField_2.Value;
                app.DepthmmEditField_2.Value = ceil(maxZ - minZ);
                app.billet.depth = app.DepthmmEditField_2.Value;
            end
        end

        % Value changed function: NoPointsEditField
        function NoPointsEditFieldValueChanged(app, event)
            if app.NoPointsEditField.Value < 10
                app.WarningText.Value = [   'Not enough points for a profile!', newline,newline,...
                                            'Please, choose a higher number of points to compose the projected cutting profiles in the final G-code.'];
            else
                app.KerfEditField.Value     = 0;
% % % % % % % % 
                vec                 = [app.stl.root.vertices(:,2),app.stl.root.vertices(:,3)];
                tq                  = linspace(0,1,app.NoPointsEditField.Value);          
                yzq                 = interpclosed(vec(:,1)',vec(:,2)',tq, 'spline');
                app.stl.rootProfile = [ones(size(yzq,2),1)*mean(app.stl.root.vertices(:,1)), yzq(1,:)', yzq(2,:)'];
% % % % % % % % 
                cla(app.UIAxes6);
                plot3(app.stl.rootProfile(:,1),app.stl.rootProfile(:,2),app.stl.rootProfile(:,3),'ro', 'markerfacecolor', 'r', 'markeredgecolor', 'r', 'Parent',app.UIAxes6);
                app.UIAxes6.View = [90, 0];
% % % % % % % % 
%                 app.stl.rootProfileNormalsFace = zeros(app.NoPointsEditField.Value-1, 3);
%                 for i = 2:1:length(app.stl.rootProfile)
%                     dy = app.stl.rootProfile(i,2)-app.stl.rootProfile(i-1,2);
%                     dz = app.stl.rootProfile(i,3)-app.stl.rootProfile(i-1,3);
%     
%                     app.stl.rootProfileNormalsFace(i-1,1) = 0;
%                     app.stl.rootProfileNormalsFace(i-1,2) = dz;
%                     app.stl.rootProfileNormalsFace(i-1,3) = -dy;
%                     app.stl.rootProfileNormalsFace(i-1,:) = app.stl.rootProfileNormalsFace(i-1,:)/norm(app.stl.rootProfileNormalsFace(i-1,:));
%                 end
%     
%                 app.stl.rootProfileNormalsVertex = zeros(app.NoPointsEditField.Value, 3);
%                 for i = 1:1:length(app.stl.rootProfile)-1
%                     
%                     if i == 1
%                         app.stl.rootProfileNormalsVertex(i,:) = (app.stl.rootProfileNormalsFace(i,:) + app.stl.rootProfileNormalsFace(end,:))/2;
%                     else
%                         app.stl.rootProfileNormalsVertex(i,:) = (app.stl.rootProfileNormalsFace(i,:) + app.stl.rootProfileNormalsFace(i-1,:))/2;
%                     end
%                     
%                     app.stl.rootProfileNormalsVertex(i,:) = app.stl.rootProfileNormalsVertex(i,:)/norm(app.stl.rootProfileNormalsVertex(i,:));
%                 end
%                 app.stl.rootProfileNormalsVertex(end,:) = app.stl.rootProfileNormalsVertex(1,:);
% % % % % % % %      
                if strcmp(app.GeometryDropDown.Value, 'Straight') == 1
                    app.stl.tipProfile      = app.stl.rootProfile;
                    app.stl.tipProfile(:,1) = app.stl.tip.vertices(1,1);
                elseif strcmp(app.GeometryDropDown.Value, 'Tapered') == 1
                    vec                 = [app.stl.tip.vertices(:,2),app.stl.tip.vertices(:,3)];
                    tq                  = linspace(0,1,app.NoPointsEditField.Value);
                    yzq                 = interpclosed(vec(:,1)',vec(:,2)',tq, 'spline');
                    app.stl.tipProfile  = [ones(size(yzq,2),1)*mean(app.stl.tip.vertices(:,1)), yzq(1,:)', yzq(2,:)'];
                end
% % % % % % % 
                cla(app.UIAxes7);
                plot3(app.stl.tipProfile(:,1),app.stl.tipProfile(:,2),app.stl.tipProfile(:,3),'bo', 'markerfacecolor', 'b', 'markeredgecolor', 'b', 'Parent',app.UIAxes7);
                app.UIAxes7.View = [90, 0];
% % % % % % % % 
%                 app.stl.tipProfileNormalsFace = zeros(app.NoPointsEditField.Value-1, 3);
%                 for i = 2:1:length(app.stl.rootProfile)
%                     dy = app.stl.tipProfile(i,2)-app.stl.tipProfile(i-1,2);
%                     dz = app.stl.tipProfile(i,3)-app.stl.tipProfile(i-1,3);
%     
%                     app.stl.tipProfileNormalsFace(i-1,1) = 0;
%                     app.stl.tipProfileNormalsFace(i-1,2) = dz;
%                     app.stl.tipProfileNormalsFace(i-1,3) = -dy;
%                     app.stl.tipProfileNormalsFace(i-1,:) = app.stl.tipProfileNormalsFace(i-1,:)/norm(app.stl.tipProfileNormalsFace(i-1,:));
%                 end
%     
%                 app.stl.tipProfileNormalsVertex = zeros(app.NoPointsEditField.Value, 3);
%                 for i = 1:1:length(app.stl.rootProfile)-1
%                     
%                     if i == 1
%                         app.stl.tipProfileNormalsVertex(i,:) = (app.stl.tipProfileNormalsFace(i,:) + app.stl.tipProfileNormalsFace(end,:))/2;
%                     else
%                         app.stl.tipProfileNormalsVertex(i,:) = (app.stl.tipProfileNormalsFace(i,:) + app.stl.tipProfileNormalsFace(i-1,:))/2;
%                     end
%                     
%                     app.stl.tipProfileNormalsVertex(i,:) = app.stl.tipProfileNormalsVertex(i,:)/norm(app.stl.tipProfileNormalsVertex(i,:));
%                 end
%                 app.stl.tipProfileNormalsVertex(end,:) = app.stl.tipProfileNormalsVertex(1,:);
% % % % % % % % 
                minX = min(min(app.stl.tipProfile(:,1)),min(app.stl.rootProfile(:,1)));
                minY = min(min(app.stl.tipProfile(:,2)),min(app.stl.rootProfile(:,2)));
                minZ = min(min(app.stl.tipProfile(:,3)),min(app.stl.rootProfile(:,3)));
                maxX = max(max(app.stl.tipProfile(:,1)),max(app.stl.rootProfile(:,1)));
                maxY = max(max(app.stl.tipProfile(:,2)),max(app.stl.rootProfile(:,2)));
                maxZ = max(max(app.stl.tipProfile(:,3)),max(app.stl.rootProfile(:,3)));
                
                app.WidthmmEditField_2.Value = ceil(maxX - minX);
                app.billet.width = app.WidthmmEditField_2.Value;
                app.LengthmmEditField_2.Value = ceil(maxY - minY);
                app.billet.length = app.LengthmmEditField_2.Value;
                app.DepthmmEditField_2.Value = ceil(maxZ - minZ);
                app.billet.depth = app.DepthmmEditField_2.Value;

                cla(app.UIAxes3);
                cla(app.UIAxes4);
                cla(app.UIAxes5);
            end
        end

        % Value changed function: KerfEditField
        function KerfEditFieldValueChanged(app, event)
            if app.NoPointsEditField.Value == 0
                app.WarningText.Value = 'Please, first choose the number of points that will compose the projected cutting profiles in the final GCODE.';
            else
% % % % % % % % 
                offsetRoot                  = polybuffer(polyshape(app.stl.rootProfile(:,2), app.stl.rootProfile(:,3), 'Simplify', 0), app.KerfEditField.Value, 'JointType', 'miter');
                app.stl.offsetRootProfile   = [ones(length(offsetRoot.Vertices),1)*app.stl.rootProfile(1,1), offsetRoot.Vertices(:,1), offsetRoot.Vertices(:,2)];    

%               Re-interpolatting the desired no of points  
                vec                         = [app.stl.offsetRootProfile(:,2),app.stl.offsetRootProfile(:,3)];
                tq                          = linspace(0,1,app.NoPointsEditField.Value-1);          
                yzq                         = interpclosed(vec(:,1)',vec(:,2)',tq, 'spline');
                xq                          = ones(size(yzq,2),1)*mean(app.stl.offsetRootProfile(:,1));
                app.stl.offsetRootProfile   = [xq, yzq(1,:)', yzq(2,:)'];
                
%               Re-ordering points starting from left-most
                idx = find(app.stl.offsetRootProfile(:,2) == min(app.stl.offsetRootProfile(:,2)));
                idxOrder = linspace(1, app.NoPointsEditField.Value-1, app.NoPointsEditField.Value-1)';
                idxOrder = circshift(idxOrder, -(idx-1));
                app.stl.offsetRootProfile = app.stl.offsetRootProfile(idxOrder,:);

%               Re-inserting last point = first point
                app.stl.offsetRootProfile = [app.stl.offsetRootProfile; app.stl.offsetRootProfile(1,:)];

                if strcmp(app.GeometryDropDown.Value, 'Straight') == 1
                    app.stl.offsetTipProfile        = app.stl.offsetRootProfile;
                    app.stl.offsetTipProfile(:,1)   = app.stl.tipProfile(1,1); 
                elseif strcmp(app.GeometryDropDown.Value, 'Tapered') == 1
                    offsetTip                   = polybuffer(polyshape(app.stl.tipProfile(:,2), app.stl.tipProfile(:,3), 'Simplify', 0), app.KerfEditField.Value, 'JointType', 'miter');
                    app.stl.offsetTipProfile    = [ones(length(offsetTip.Vertices),1)*app.stl.tipProfile(1,1), offsetTip.Vertices(:,1), offsetTip.Vertices(:,2)];  

%               Re-interpolatting the desired no of points
                    vec                         = [app.stl.offsetTipProfile(:,2),app.stl.offsetTipProfile(:,3)];
                    tq                          = linspace(0,1,app.NoPointsEditField.Value-1);          
                    yzq                         = interpclosed(vec(:,1)',vec(:,2)',tq, 'spline');
                    xq                          = ones(size(yzq,2),1)*mean(app.stl.offsetTipProfile(:,1));
                    app.stl.offsetTipProfile   = [xq, yzq(1,:)', yzq(2,:)'];

%               Re-ordering points starting from left-most
                idx = find(app.stl.offsetTipProfile(:,2) == min(app.stl.offsetTipProfile(:,2)));
                idxOrder = linspace(1, app.NoPointsEditField.Value-1, app.NoPointsEditField.Value-1)';
                idxOrder = circshift(idxOrder, -(idx-1));
                app.stl.offsetTipProfile = app.stl.offsetTipProfile(idxOrder,:);

%               Re-inserting last point = first point
                app.stl.offsetTipProfile = [app.stl.offsetTipProfile; app.stl.offsetTipProfile(1,:)];

                end


%                 keyboard;
%                 app.NoPointsEditField.Value = length(app.stl.offsetRootProfile);
                
%                 keyboard;
%                 figure(1);clf;scatter(app.stl.offsetRootProfile(:,2), app.stl.offsetRootProfile(:,3), [], linspace(1, length(app.stl.offsetRootProfile), length(app.stl.offsetRootProfile)), 'filled');
%                 figure(1);hold on;scatter(app.stl.rootProfile(:,2), app.stl.rootProfile(:,3), [], linspace(1, length(app.stl.rootProfile), length(app.stl.rootProfile)), 'filled');

%                 app.stl.offsetRootProfile = app.stl.rootProfile + app.stl.rootProfileNormalsVertex.*(app.KerfEditField.Value);
%                 app.stl.offsetTipProfile = app.stl.tipProfile + app.stl.tipProfileNormalsVertex.*(app.KerfEditField.Value);
% % % % % % % %     
%                 keyboard;
                app.stl.slicedMesh = delaunayTriangulation([app.stl.offsetRootProfile; app.stl.offsetTipProfile]);
                [app.stl.slicedMeshFaces,app.stl.slicedMeshVertices] = tetBoundaryFacets(app.stl.slicedMesh.Points', app.stl.slicedMesh.ConnectivityList');
% % % % % % % %     
                cla(app.UIAxes6);
                plot3(app.stl.rootProfile(:,1),app.stl.rootProfile(:,2),app.stl.rootProfile(:,3),'rd', 'markerfacecolor', 'w', 'markeredgecolor', 'r', 'Parent',app.UIAxes6);
                app.UIAxes6.View = [90, 0];
                hold(app.UIAxes6, "on");
                plot3(app.stl.offsetRootProfile(:,1),app.stl.offsetRootProfile(:,2),app.stl.offsetRootProfile(:,3),'ro', 'markerfacecolor', 'r', 'markeredgecolor', 'r', 'Parent',app.UIAxes6);
                hold(app.UIAxes6, "off");
    
                cla(app.UIAxes7);
                plot3(app.stl.tipProfile(:,1),app.stl.tipProfile(:,2),app.stl.tipProfile(:,3),'bd', 'markerfacecolor', 'w', 'markeredgecolor', 'b', 'Parent',app.UIAxes7);
                app.UIAxes7.View = [90, 0];
                hold(app.UIAxes7, "on");
                plot3(app.stl.offsetTipProfile(:,1),app.stl.offsetTipProfile(:,2),app.stl.offsetTipProfile(:,3),'bo', 'markerfacecolor', 'b', 'markeredgecolor', 'b', 'Parent',app.UIAxes7);
                hold(app.UIAxes7, "off");
% % % % % % % %     
                minX = min(min(app.stl.offsetTipProfile(:,1)),min(app.stl.offsetRootProfile(:,1)));
                minY = min(min(app.stl.offsetTipProfile(:,2)),min(app.stl.offsetRootProfile(:,2)));
                minZ = min(min(app.stl.offsetTipProfile(:,3)),min(app.stl.offsetRootProfile(:,3)));
                maxX = max(max(app.stl.offsetTipProfile(:,1)),max(app.stl.offsetRootProfile(:,1)));
                maxY = max(max(app.stl.offsetTipProfile(:,2)),max(app.stl.offsetRootProfile(:,2)));
                maxZ = max(max(app.stl.offsetTipProfile(:,3)),max(app.stl.offsetRootProfile(:,3)));
                
                app.WidthmmEditField_2.Value = ceil(maxX - minX);
                app.billet.width = app.WidthmmEditField_2.Value;
                app.LengthmmEditField_2.Value = ceil(maxY - minY);
                app.billet.length = app.LengthmmEditField_2.Value;
                app.DepthmmEditField_2.Value = ceil(maxZ - minZ);
                app.billet.depth = app.DepthmmEditField_2.Value;

                cla(app.UIAxes3);
                cla(app.UIAxes4);
                cla(app.UIAxes5);
            end
        end

        % Value changed function: WidthmmEditField_2
        function WidthmmEditField_2ValueChanged(app, event)
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            app.billet.width = app.WidthmmEditField_2.Value;
        end

        % Value changed function: LengthmmEditField_2
        function LengthmmEditField_2ValueChanged(app, event)
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            app.billet.length = app.LengthmmEditField_2.Value;
        end

        % Value changed function: DepthmmEditField_2
        function DepthmmEditField_2ValueChanged(app, event)
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            app.billet.depth = app.DepthmmEditField_2.Value;
        end

        % Button pushed function: LoadBilletButton
        function LoadBilletButtonPushed(app, event)
            dx = app.billet.width;
            dy = app.billet.length;
            dz = app.billet.depth;
            
            p1 = [app.billet.origin(1),      app.billet.origin(2),        app.billet.origin(3)];
            p2 = [app.billet.origin(1)+dx,   app.billet.origin(2),        app.billet.origin(3)];
            p3 = [app.billet.origin(1)+dx,   app.billet.origin(2),        app.billet.origin(3)+dz];
            p4 = [app.billet.origin(1),      app.billet.origin(2),        app.billet.origin(3)+dz];
            p5 = [app.billet.origin(1),      app.billet.origin(2)+dy,     app.billet.origin(3)];
            p6 = [app.billet.origin(1)+dx,   app.billet.origin(2)+dy,     app.billet.origin(3)];
            p7 = [app.billet.origin(1)+dx,   app.billet.origin(2)+dy,     app.billet.origin(3)+dz];
            p8 = [app.billet.origin(1),      app.billet.origin(2)+dy,     app.billet.origin(3)+dz];
            billetPointsF1 = [p1; p2; p3; p4];
            billetPointsF2 = [p2; p6; p7; p3];
            billetPointsF3 = [p6; p5; p8; p7];
            billetPointsF4 = [p1; p4; p8; p5];
            billetPointsF5 = [p4; p3; p7; p8];
            billetPointsF6 = [p1; p5; p6; p2];

            cla(app.UIAxes3);
            fill3(billetPointsF1(:,1), billetPointsF1(:,2), billetPointsF1(:,3), 'c',...
            billetPointsF2(:,1), billetPointsF2(:,2), billetPointsF2(:,3), 'c',...
            billetPointsF3(:,1), billetPointsF3(:,2), billetPointsF3(:,3), 'c',...
            billetPointsF4(:,1), billetPointsF4(:,2), billetPointsF4(:,3), 'c',...
            billetPointsF5(:,1), billetPointsF5(:,2), billetPointsF5(:,3), 'c',...
            billetPointsF6(:,1), billetPointsF6(:,2), billetPointsF6(:,3), 'c','FaceAlpha', 0.5,'Parent', app.UIAxes3);
            app.UIAxes3.View = [0, 90];
            set(app.UIAxes3, 'XLim', [app.billet.origin(1), app.billet.origin(1)+app.billet.width]);
            set(app.UIAxes3, 'YLim', [app.billet.origin(2), app.billet.origin(2)+app.billet.length]);
            cla(app.UIAxes4);
            fill3(billetPointsF1(:,1), billetPointsF1(:,2), billetPointsF1(:,3), 'c',...
            billetPointsF2(:,1), billetPointsF2(:,2), billetPointsF2(:,3), 'c',...
            billetPointsF3(:,1), billetPointsF3(:,2), billetPointsF3(:,3), 'c',...
            billetPointsF4(:,1), billetPointsF4(:,2), billetPointsF4(:,3), 'c',...
            billetPointsF5(:,1), billetPointsF5(:,2), billetPointsF5(:,3), 'c',...
            billetPointsF6(:,1), billetPointsF6(:,2), billetPointsF6(:,3), 'c','FaceAlpha', 0.5,'Parent', app.UIAxes4);
            app.UIAxes4.View = [90, 0];
            set(app.UIAxes4, 'YLim', [app.billet.origin(2), app.billet.origin(2)+app.billet.length]);
            set(app.UIAxes4, 'ZLim', [app.billet.origin(3), app.billet.origin(3)+app.billet.depth]);
            cla(app.UIAxes5);
            fill3(billetPointsF1(:,1), billetPointsF1(:,2), billetPointsF1(:,3), 'c',...
            billetPointsF2(:,1), billetPointsF2(:,2), billetPointsF2(:,3), 'c',...
            billetPointsF3(:,1), billetPointsF3(:,2), billetPointsF3(:,3), 'c',...
            billetPointsF4(:,1), billetPointsF4(:,2), billetPointsF4(:,3), 'c',...
            billetPointsF5(:,1), billetPointsF5(:,2), billetPointsF5(:,3), 'c',...
            billetPointsF6(:,1), billetPointsF6(:,2), billetPointsF6(:,3), 'c','FaceAlpha', 0.5,'Parent', app.UIAxes5);
            app.UIAxes5.View = [0, 0];
            set(app.UIAxes5, 'XLim', [app.billet.origin(1), app.billet.origin(1)+app.billet.width]);
            set(app.UIAxes5, 'ZLim', [app.billet.origin(3), app.billet.origin(3)+app.billet.depth]);
        end

        % Button pushed function: InsertModelButton
        function InsertModelButtonPushed(app, event)
            hold(app.UIAxes3, 'on');
            set(app.UIAxes3.Parent.Parent.Parent.Parent.Parent.Parent, 'renderer', 'opengl');
            trisurf(app.stl.slicedMeshFaces, app.stl.slicedMeshVertices(:,1),app.stl.slicedMeshVertices(:,2),app.stl.slicedMeshVertices(:,3),'FaceColor','g', 'EdgeColor','k','FaceAlpha', 1, 'Clipping','off', 'Parent', app.UIAxes3);

            hold(app.UIAxes4, 'on');
            set(app.UIAxes4.Parent.Parent.Parent.Parent.Parent.Parent, 'renderer', 'opengl');
            trisurf(app.stl.slicedMeshFaces, app.stl.slicedMeshVertices(:,1),app.stl.slicedMeshVertices(:,2),app.stl.slicedMeshVertices(:,3),'FaceColor','g', 'EdgeColor','k','FaceAlpha', 1, 'Clipping','off', 'Parent', app.UIAxes4);
            
            hold(app.UIAxes5, 'on');
            set(app.UIAxes5.Parent.Parent.Parent.Parent.Parent.Parent, 'renderer', 'opengl');
            trisurf(app.stl.slicedMeshFaces, app.stl.slicedMeshVertices(:,1),app.stl.slicedMeshVertices(:,2),app.stl.slicedMeshVertices(:,3),'FaceColor','g', 'EdgeColor','k','FaceAlpha', 1, 'Clipping','off', 'Parent', app.UIAxes5);

            app.XmmEditField.Value    = min(app.stl.slicedMeshVertices(:,1)) - app.billet.origin(1);
            app.XmmEditField_2.Value  = (app.billet.origin(1)+app.billet.width) - max(app.stl.slicedMeshVertices(:,1));
            app.YmmEditField.Value    = min(app.stl.slicedMeshVertices(:,2)) - app.billet.origin(2);
            app.YmmEditField_2.Value  = (app.billet.origin(2)+app.billet.length) - max(app.stl.slicedMeshVertices(:,2));
            app.ZmmEditField.Value    = min(app.stl.slicedMeshVertices(:,3)) - app.billet.origin(3);
            app.ZmmEditField_2.Value  = (app.billet.origin(3)+app.billet.depth) - max(app.stl.slicedMeshVertices(:,3));


        end

        % Value changed function: TranslateXSpinner_2
        function TranslateXSpinner_2ValueChanged(app, event)
            deltaX                              = app.TranslateXSpinner_2.Value;
            
%             app.stl.points(1,:)                 = app.stl.points(1,:) + (deltaX-app.stl.transX);
%             [app.stl.faces, app.stl.vertices]   = tetBoundaryFacets(app.stl.points,app.stl.tetra(1:4,:));

            app.stl.offsetRootProfile(:,1)                          = app.stl.offsetRootProfile(:,1) + (deltaX-app.stl.transX);
            app.stl.offsetTipProfile(:,1)                           = app.stl.offsetTipProfile(:,1) + (deltaX-app.stl.transX);
            
            app.stl.slicedMesh                                      = delaunayTriangulation([app.stl.offsetRootProfile; app.stl.offsetTipProfile]);
            [app.stl.slicedMeshFaces,app.stl.slicedMeshVertices]    = tetBoundaryFacets(app.stl.slicedMesh.Points', app.stl.slicedMesh.ConnectivityList');

            app.stl.transX                      = deltaX;

            app.LoadBilletButtonPushed(event);
            app.InsertModelButtonPushed(event);
        end

        % Value changed function: TranslateYSpinner_2
        function TranslateYSpinner_2ValueChanged(app, event)
            deltaY                              = app.TranslateYSpinner_2.Value;
            
%             app.stl.points(2,:)                 = app.stl.points(2,:) + (deltaY-app.stl.transY);
%             [app.stl.faces, app.stl.vertices]   = tetBoundaryFacets(app.stl.points,app.stl.tetra(1:4,:));

            app.stl.offsetRootProfile(:,2)            = app.stl.offsetRootProfile(:,2) + (deltaY-app.stl.transY);
            app.stl.offsetTipProfile(:,2)             = app.stl.offsetTipProfile(:,2) + (deltaY-app.stl.transY);
            
            app.stl.slicedMesh                                      = delaunayTriangulation([app.stl.offsetRootProfile; app.stl.offsetTipProfile]);
            [app.stl.slicedMeshFaces,app.stl.slicedMeshVertices]    = tetBoundaryFacets(app.stl.slicedMesh.Points', app.stl.slicedMesh.ConnectivityList');

            app.stl.transY                      = deltaY;

            app.LoadBilletButtonPushed(event);
            app.InsertModelButtonPushed(event);
            
        end

        % Value changed function: TranslateZSpinner_2
        function TranslateZSpinner_2ValueChanged(app, event)
            deltaZ                              = app.TranslateZSpinner_2.Value;
            
%             app.stl.points(3,:)                 = app.stl.points(3,:) + (deltaZ-app.stl.transZ);
%             [app.stl.faces, app.stl.vertices]   = tetBoundaryFacets(app.stl.points,app.stl.tetra(1:4,:));

            app.stl.offsetRootProfile(:,3)            = app.stl.offsetRootProfile(:,3) + (deltaZ-app.stl.transZ);
            app.stl.offsetTipProfile(:,3)             = app.stl.offsetTipProfile(:,3) + (deltaZ-app.stl.transZ);
            
            app.stl.slicedMesh                                      = delaunayTriangulation([app.stl.offsetRootProfile; app.stl.offsetTipProfile]);
            [app.stl.slicedMeshFaces,app.stl.slicedMeshVertices]    = tetBoundaryFacets(app.stl.slicedMesh.Points', app.stl.slicedMesh.ConnectivityList');

            app.stl.transZ                      = deltaZ;

            app.LoadBilletButtonPushed(event);
            app.InsertModelButtonPushed(event);
            
        end

        % Button pushed function: LoadCNCButton
        function LoadCNCButtonPushed(app, event)
%             app.BedWidthmmEditField.Value = app.cnc.width;
%             app.BedLengthmmEditField.Value = app.cnc.length;
%             app.BedDepthmmEditField.Value = app.cnc.depth;

            dx = app.cnc.bedW;            
            dy = app.cnc.bedL;
            dz = app.cnc.bedD;
            p1 = [app.cnc.bedShiftX,    app.cnc.bedShiftY,      app.cnc.bedShiftZ];
            p2 = [app.cnc.bedShiftX+dx, app.cnc.bedShiftY,      app.cnc.bedShiftZ];
            p3 = [app.cnc.bedShiftX+dx, app.cnc.bedShiftY,      dz];
            p4 = [app.cnc.bedShiftX,    app.cnc.bedShiftY,      dz];
            p5 = [app.cnc.bedShiftX,    app.cnc.bedShiftY+dy,   app.cnc.bedShiftZ];
            p6 = [app.cnc.bedShiftX+dx, app.cnc.bedShiftY+dy,   app.cnc.bedShiftZ];
            p7 = [app.cnc.bedShiftX+dx, app.cnc.bedShiftY+dy,   dz];
            p8 = [app.cnc.bedShiftX,    app.cnc.bedShiftY+dy,   dz];
            cncPointsF1 = [p1; p2; p3; p4];
            cncPointsF2 = [p2; p6; p7; p3];
            cncPointsF3 = [p6; p5; p8; p7];
            cncPointsF4 = [p1; p4; p8; p5];
            cncPointsF5 = [p4; p3; p7; p8];
            cncPointsF6 = [p1; p5; p6; p2];

            fill3(cncPointsF1(:,1), cncPointsF1(:,2), cncPointsF1(:,3), 'c',...
            cncPointsF2(:,1), cncPointsF2(:,2), cncPointsF2(:,3), 'c',...
            cncPointsF3(:,1), cncPointsF3(:,2), cncPointsF3(:,3), 'c',...
            cncPointsF4(:,1), cncPointsF4(:,2), cncPointsF4(:,3), 'c',...
            cncPointsF5(:,1), cncPointsF5(:,2), cncPointsF5(:,3), 'c',...
            cncPointsF6(:,1), cncPointsF6(:,2), cncPointsF6(:,3), 'c','FaceAlpha', 1,'Parent', app.UIAxes2);
            hold(app.UIAxes2, 'on');

            dx = app.cnc.cutW;            
            dy = app.cnc.cutL;
            dz = app.cnc.cutD;
            p1 = [app.cnc.cutShiftX,    app.cnc.cutShiftY,      app.cnc.cutShiftZ];
            p2 = [app.cnc.cutShiftX+dx, app.cnc.cutShiftY,      app.cnc.cutShiftZ];
            p3 = [app.cnc.cutShiftX+dx, app.cnc.cutShiftY,      app.cnc.cutShiftZ+dz];
            p4 = [app.cnc.cutShiftX,    app.cnc.cutShiftY,      app.cnc.cutShiftZ+dz];
            p5 = [app.cnc.cutShiftX,    app.cnc.cutShiftY+dy,   app.cnc.cutShiftZ];
            p6 = [app.cnc.cutShiftX+dx, app.cnc.cutShiftY+dy,   app.cnc.cutShiftZ];
            p7 = [app.cnc.cutShiftX+dx, app.cnc.cutShiftY+dy,   app.cnc.cutShiftZ+dz];
            p8 = [app.cnc.cutShiftX,    app.cnc.cutShiftY+dy,   app.cnc.cutShiftZ+dz];
            cncPointsF1 = [p1; p2; p3; p4];
            cncPointsF2 = [p2; p6; p7; p3];
            cncPointsF3 = [p6; p5; p8; p7];
            cncPointsF4 = [p1; p4; p8; p5];
            cncPointsF5 = [p4; p3; p7; p8];
            cncPointsF6 = [p1; p5; p6; p2];

            fill3(cncPointsF1(:,1), cncPointsF1(:,2), cncPointsF1(:,3), 'w',...
            cncPointsF2(:,1), cncPointsF2(:,2), cncPointsF2(:,3), 'w',...
            cncPointsF3(:,1), cncPointsF3(:,2), cncPointsF3(:,3), 'w',...
            cncPointsF4(:,1), cncPointsF4(:,2), cncPointsF4(:,3), 'w',...
            cncPointsF5(:,1), cncPointsF5(:,2), cncPointsF5(:,3), 'w',...
            cncPointsF6(:,1), cncPointsF6(:,2), cncPointsF6(:,3), 'w','FaceAlpha', 0.05,'Parent', app.UIAxes2);


            dx = app.cnc.boxW;            
            dy = app.cnc.boxL;
            dz = app.cnc.boxD;
            p1 = [app.cnc.boxShiftX,    app.cnc.boxShiftY,      app.cnc.boxShiftZ];
            p2 = [app.cnc.boxShiftX+dx, app.cnc.boxShiftY,      app.cnc.boxShiftZ];
            p3 = [app.cnc.boxShiftX+dx, app.cnc.boxShiftY,      app.cnc.boxShiftZ+dz];
            p4 = [app.cnc.boxShiftX,    app.cnc.boxShiftY,      app.cnc.boxShiftZ+dz];
            p5 = [app.cnc.boxShiftX,    app.cnc.boxShiftY+dy,   app.cnc.boxShiftZ];
            p6 = [app.cnc.boxShiftX+dx, app.cnc.boxShiftY+dy,   app.cnc.boxShiftZ];
            p7 = [app.cnc.boxShiftX+dx, app.cnc.boxShiftY+dy,   app.cnc.boxShiftZ+dz];
            p8 = [app.cnc.boxShiftX,    app.cnc.boxShiftY+dy,   app.cnc.boxShiftZ+dz];
            cncPointsF1 = [p1; p2; p3; p4];
            cncPointsF2 = [p2; p6; p7; p3];
            cncPointsF3 = [p6; p5; p8; p7];
            cncPointsF4 = [p1; p4; p8; p5];
            cncPointsF5 = [p4; p3; p7; p8];
            cncPointsF6 = [p1; p5; p6; p2];

            fill3(cncPointsF1(:,1), cncPointsF1(:,2), cncPointsF1(:,3), 'w',...
            cncPointsF2(:,1), cncPointsF2(:,2), cncPointsF2(:,3), 'w',...
            cncPointsF3(:,1), cncPointsF3(:,2), cncPointsF3(:,3), 'w',...
            cncPointsF4(:,1), cncPointsF4(:,2), cncPointsF4(:,3), 'w',...
            cncPointsF5(:,1), cncPointsF5(:,2), cncPointsF5(:,3), 'w',...
            cncPointsF6(:,1), cncPointsF6(:,2), cncPointsF6(:,3), 'w','FaceAlpha', 0.05,'Parent', app.UIAxes2);


            xyHeadPlane.vertices = [    app.cnc.xyHeadPlaneX, 0,            0;
                                        app.cnc.xyHeadPlaneX, 0,            app.cnc.cutD;
                                        app.cnc.xyHeadPlaneX, app.cnc.cutL, app.cnc.cutD;
                                        app.cnc.xyHeadPlaneX, app.cnc.cutL, 0];
            xyHeadPlane.faces    = [    1, 2, 3;
                                        1, 3, 4];
            p1 = patch(app.UIAxes2, xyHeadPlane.vertices(:,1), xyHeadPlane.vertices(:,2), xyHeadPlane.vertices(:,3), 'r');
            p1.FaceVertexAlphaData  = 0.05;
            p1.FaceAlpha            = 'flat' ;
            
            zaHeadPlane.vertices = [    app.cnc.zaHeadPlaneX, 0,            0;
                                        app.cnc.zaHeadPlaneX, 0,            app.cnc.cutD;
                                        app.cnc.zaHeadPlaneX, app.cnc.cutL, app.cnc.cutD;
                                        app.cnc.zaHeadPlaneX, app.cnc.cutL, 0];
            zaHeadPlane.faces    = [    1, 2, 3;
                                        1, 3, 4];
            p2 = patch(app.UIAxes2, zaHeadPlane.vertices(:,1), zaHeadPlane.vertices(:,2), zaHeadPlane.vertices(:,3), 'b');
            p2.FaceVertexAlphaData  = 0.05;
            p2.FaceAlpha            = 'flat' ;


        end

        % Button pushed function: InsertBilletButton
        function InsertBilletButtonPushed(app, event)
            dx = app.billet.width;
            dy = app.billet.length;
            dz = app.billet.depth;
            
            p1 = [app.billet.origin(1),      app.billet.origin(2),        app.billet.origin(3)];
            p2 = [app.billet.origin(1)+dx,   app.billet.origin(2),        app.billet.origin(3)];
            p3 = [app.billet.origin(1)+dx,   app.billet.origin(2),        app.billet.origin(3)+dz];
            p4 = [app.billet.origin(1),      app.billet.origin(2),        app.billet.origin(3)+dz];
            p5 = [app.billet.origin(1),      app.billet.origin(2)+dy,     app.billet.origin(3)];
            p6 = [app.billet.origin(1)+dx,   app.billet.origin(2)+dy,     app.billet.origin(3)];
            p7 = [app.billet.origin(1)+dx,   app.billet.origin(2)+dy,     app.billet.origin(3)+dz];
            p8 = [app.billet.origin(1),      app.billet.origin(2)+dy,     app.billet.origin(3)+dz];
            billetPointsF1 = [p1; p2; p3; p4];
            billetPointsF2 = [p2; p6; p7; p3];
            billetPointsF3 = [p6; p5; p8; p7];
            billetPointsF4 = [p1; p4; p8; p5];
            billetPointsF5 = [p4; p3; p7; p8];
            billetPointsF6 = [p1; p5; p6; p2];

            hold(app.UIAxes2, 'on');
            fill3(billetPointsF1(:,1), billetPointsF1(:,2), billetPointsF1(:,3), 'c',...
            billetPointsF2(:,1), billetPointsF2(:,2), billetPointsF2(:,3), 'c',...
            billetPointsF3(:,1), billetPointsF3(:,2), billetPointsF3(:,3), 'c',...
            billetPointsF4(:,1), billetPointsF4(:,2), billetPointsF4(:,3), 'c',...
            billetPointsF5(:,1), billetPointsF5(:,2), billetPointsF5(:,3), 'c',...
            billetPointsF6(:,1), billetPointsF6(:,2), billetPointsF6(:,3), 'c','FaceAlpha', 0.5,'Parent', app.UIAxes2);
            hold(app.UIAxes2, 'off');
        end

        % Button pushed function: InsertModelButton_2
        function InsertModelButton_2Pushed(app, event)
            hold(app.UIAxes2, 'on');
            set(app.UIAxes2.Parent.Parent.Parent.Parent.Parent.Parent, 'renderer', 'opengl');
            trisurf(app.stl.slicedMeshFaces, app.stl.slicedMeshVertices(:,1),app.stl.slicedMeshVertices(:,2),app.stl.slicedMeshVertices(:,3),'FaceColor','g', 'EdgeColor','k','FaceAlpha', 1, 'Clipping','off', 'Parent', app.UIAxes2);
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %             
            app.stl.projRootProfile = zeros(size(app.stl.offsetRootProfile));
            app.stl.projTipProfile  = zeros(size(app.stl.offsetTipProfile));

            for i = 1:1:length(app.stl.offsetRootProfile)
                app.stl.projRootProfile(i,1)    = app.cnc.xyHeadPlaneX;
                app.stl.projTipProfile(i,1)     = app.cnc.zaHeadPlaneX;
                xzProj                          = polyfit([app.stl.offsetRootProfile(i,1), app.stl.offsetTipProfile(i,1)], [app.stl.offsetRootProfile(i,2), app.stl.offsetTipProfile(i,2)], 1);
                app.stl.projRootProfile(i,2)    = xzProj(1)*app.cnc.xyHeadPlaneX + xzProj(2);
                app.stl.projTipProfile(i,2)     = xzProj(1)*app.cnc.zaHeadPlaneX + xzProj(2);
                yaProj                          = polyfit([app.stl.offsetRootProfile(i,1), app.stl.offsetTipProfile(i,1)], [app.stl.offsetRootProfile(i,3), app.stl.offsetTipProfile(i,3)], 1);
                app.stl.projRootProfile(i,3)    = yaProj(1)*app.cnc.xyHeadPlaneX + yaProj(2);
                app.stl.projTipProfile(i,3)     = yaProj(1)*app.cnc.zaHeadPlaneX + yaProj(2);
            end
            
            app.stl.projRootProfile = app.stl.projRootProfile(1:1:end-1,:);
            app.stl.projTipProfile  = app.stl.projTipProfile(1:1:end-1,:);
%             keyboard;
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %     
            app.stl.cutRootProfile = zeros(size(app.stl.offsetRootProfile));
            app.stl.cutTipProfile  = zeros(size(app.stl.offsetTipProfile));

            for i = 1:1:length(app.stl.offsetRootProfile)
                app.stl.cutRootProfile(i,1)    = app.billet.origin(1);
                app.stl.cutTipProfile(i,1)     = (app.billet.origin(1) + app.billet.width);

                xzProj                         = polyfit([app.stl.offsetRootProfile(i,1), app.stl.offsetTipProfile(i,1)], [app.stl.offsetRootProfile(i,2), app.stl.offsetTipProfile(i,2)], 1);
                app.stl.cutRootProfile(i,2)    = xzProj(1)*app.billet.origin(1) + xzProj(2);
                app.stl.cutTipProfile(i,2)     = xzProj(1)*(app.billet.origin(1) + app.billet.width) + xzProj(2);

                yaProj                         = polyfit([app.stl.offsetRootProfile(i,1), app.stl.offsetTipProfile(i,1)], [app.stl.offsetRootProfile(i,3), app.stl.offsetTipProfile(i,3)], 1);
                app.stl.cutRootProfile(i,3)    = yaProj(1)*app.billet.origin(1) + yaProj(2);
                app.stl.cutTipProfile(i,3)     = yaProj(1)*(app.billet.origin(1) + app.billet.width) + yaProj(2);
            end
            
            app.stl.cutRootProfile = app.stl.cutRootProfile(1:1:end-1,:);
            app.stl.cutTipProfile  = app.stl.cutTipProfile(1:1:end-1,:);
            
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %             
            xyHeadPlane         = [     app.cnc.xyHeadPlaneX, 0,            0;
                                        app.cnc.xyHeadPlaneX, 0,            app.cnc.cutD;
                                        app.cnc.xyHeadPlaneX, app.cnc.cutL, app.cnc.cutD;
                                        app.cnc.xyHeadPlaneX, app.cnc.cutL, 0];
            
            [in, on] = inpolygon(app.stl.projRootProfile(:,2), app.stl.projRootProfile(:,3), xyHeadPlane(:,2), xyHeadPlane(:,3));
            app.stl.projRootProfileIdxInOn = find(in == 1 | on == 1);
            app.stl.projRootProfileIdxOut  = find(in == 0);
            plot3(app.stl.projRootProfile(app.stl.projRootProfileIdxInOn,1),app.stl.projRootProfile(app.stl.projRootProfileIdxInOn,2),app.stl.projRootProfile(app.stl.projRootProfileIdxInOn,3),'ro', 'markerfacecolor', 'r', 'markeredgecolor', 'r', 'Parent',app.UIAxes2);
            plot3(app.stl.projRootProfile(app.stl.projRootProfileIdxOut,1),app.stl.projRootProfile(app.stl.projRootProfileIdxOut,2),app.stl.projRootProfile(app.stl.projRootProfileIdxOut,3),'ko', 'markerfacecolor', 'k', 'markeredgecolor', 'k', 'Parent',app.UIAxes2);
            
            zaHeadPlane         = [     app.cnc.zaHeadPlaneX, 0,            0;
                                        app.cnc.zaHeadPlaneX, 0,            app.cnc.cutD;
                                        app.cnc.zaHeadPlaneX, app.cnc.cutL, app.cnc.cutD;
                                        app.cnc.zaHeadPlaneX, app.cnc.cutL, 0];
            [in, on] = inpolygon(app.stl.projTipProfile(:,2), app.stl.projTipProfile(:,3), zaHeadPlane(:,2), zaHeadPlane(:,3));
            app.stl.projTipProfileIdxInOn = find(in == 1 | on == 1);
            app.stl.projTipProfileIdxOut  = find(in == 0);
            plot3(app.stl.projTipProfile(app.stl.projTipProfileIdxInOn,1),app.stl.projTipProfile(app.stl.projTipProfileIdxInOn,2),app.stl.projTipProfile(app.stl.projTipProfileIdxInOn,3),'bo', 'markerfacecolor', 'b', 'markeredgecolor', 'b', 'Parent',app.UIAxes2);
            plot3(app.stl.projTipProfile(app.stl.projTipProfileIdxOut,1),app.stl.projTipProfile(app.stl.projTipProfileIdxOut,2),app.stl.projTipProfile(app.stl.projTipProfileIdxOut,3),'ko', 'markerfacecolor', 'k', 'markeredgecolor', 'k', 'Parent',app.UIAxes2);
            hold(app.UIAxes2, "off");
        end

        % Value changed function: TranslateXmmSpinner
        function TranslateXmmSpinnerValueChanged(app, event)
            deltaX                      = app.TranslateXmmSpinner.Value;
            
            app.billet.origin(1)                = app.billet.origin(1)      + (deltaX-app.billet.transX);
            
%             app.stl.points(1,:)                 = app.stl.points(1,:) + (deltaX-app.billet.transX);
%             [app.stl.faces, app.stl.vertices]   = tetBoundaryFacets(app.stl.points,app.stl.tetra(1:4,:));

            app.stl.offsetRootProfile(:,1)      = app.stl.offsetRootProfile(:,1)  + (deltaX-app.billet.transX);
            app.stl.offsetTipProfile(:,1)       = app.stl.offsetTipProfile(:,1)   + (deltaX-app.billet.transX);

            app.stl.slicedMesh                                      = delaunayTriangulation([app.stl.offsetRootProfile; app.stl.offsetTipProfile]);
            [app.stl.slicedMeshFaces,app.stl.slicedMeshVertices]    = tetBoundaryFacets(app.stl.slicedMesh.Points', app.stl.slicedMesh.ConnectivityList');
            
            app.billet.transX           = deltaX;

            app.LoadCNCButtonPushed(event);
            app.InsertBilletButtonPushed(event);
            app.InsertModelButton_2Pushed(event);
        end

        % Value changed function: TranslateYmmSpinner
        function TranslateYmmSpinnerValueChanged(app, event)
            deltaY                      = app.TranslateYmmSpinner.Value;

            app.billet.origin(2)        = app.billet.origin(2)      + (deltaY-app.billet.transY);

%             app.stl.points(2,:)                 = app.stl.points(2,:) + (deltaY-app.billet.transY);
%             [app.stl.faces, app.stl.vertices]   = tetBoundaryFacets(app.stl.points,app.stl.tetra(1:4,:));

            app.stl.offsetRootProfile(:,2)    = app.stl.offsetRootProfile(:,2)  + (deltaY-app.billet.transY);
            app.stl.offsetTipProfile(:,2)     = app.stl.offsetTipProfile(:,2)   + (deltaY-app.billet.transY);

            app.stl.slicedMesh                                      = delaunayTriangulation([app.stl.offsetRootProfile; app.stl.offsetTipProfile]);
            [app.stl.slicedMeshFaces,app.stl.slicedMeshVertices]    = tetBoundaryFacets(app.stl.slicedMesh.Points', app.stl.slicedMesh.ConnectivityList');
            
            app.billet.transY           = deltaY;

            app.LoadCNCButtonPushed(event);
            app.InsertBilletButtonPushed(event);     
            app.InsertModelButton_2Pushed(event);
        end

        % Value changed function: TranslateZmmSpinner
        function TranslateZmmSpinnerValueChanged(app, event)
            deltaZ                      = app.TranslateZmmSpinner.Value;

            app.billet.origin(3)        = app.billet.origin(3)      + (deltaZ-app.billet.transZ);

%             app.stl.points(3,:)                 = app.stl.points(3,:) + (deltaZ-app.billet.transZ);
%             [app.stl.faces, app.stl.vertices]   = tetBoundaryFacets(app.stl.points,app.stl.tetra(1:4,:));

            app.stl.offsetRootProfile(:,3)    = app.stl.offsetRootProfile(:,3)  + (deltaZ-app.billet.transZ);
            app.stl.offsetTipProfile(:,3)     = app.stl.offsetTipProfile(:,3)   + (deltaZ-app.billet.transZ);

            app.stl.slicedMesh                                      = delaunayTriangulation([app.stl.offsetRootProfile; app.stl.offsetTipProfile]);
            [app.stl.slicedMeshFaces,app.stl.slicedMeshVertices]    = tetBoundaryFacets(app.stl.slicedMesh.Points', app.stl.slicedMesh.ConnectivityList');
            
            app.billet.transZ           = deltaZ;

            app.LoadCNCButtonPushed(event);
            app.InsertBilletButtonPushed(event);    
            app.InsertModelButton_2Pushed(event);
        end

        % Button pushed function: InsertProjectedProfilesButton
        function InsertProjectedProfilesButtonPushed(app, event)
            if strcmp(event.EventName,'ButtonPushed') == 1               
                delete(findobj(app.UIAxes8, 'tag', 'startPtHighlight'))
                delete(findobj(app.UIAxes9, 'tag', 'startPtHighlight'))
                delete(findobj(app.UIAxes8, 'tag', 'entryPtHighlight'))
                delete(findobj(app.UIAxes9, 'tag', 'entryPtHighlight'))

                cla(app.UIAxes8);
                cla(app.UIAxes9);
            end

            buffL = 10;
            buffH = 10;

            dx = app.billet.width;
            dy = app.billet.length;
            dz = app.billet.depth;
            
            p1 = [app.billet.origin(1),      app.billet.origin(2),        app.billet.origin(3)];
            p2 = [app.billet.origin(1)+dx,   app.billet.origin(2),        app.billet.origin(3)];
            p3 = [app.billet.origin(1)+dx,   app.billet.origin(2),        app.billet.origin(3)+dz];
            p4 = [app.billet.origin(1),      app.billet.origin(2),        app.billet.origin(3)+dz];
            p5 = [app.billet.origin(1),      app.billet.origin(2)+dy,     app.billet.origin(3)];
            p6 = [app.billet.origin(1)+dx,   app.billet.origin(2)+dy,     app.billet.origin(3)];
            p7 = [app.billet.origin(1)+dx,   app.billet.origin(2)+dy,     app.billet.origin(3)+dz];
            p8 = [app.billet.origin(1),      app.billet.origin(2)+dy,     app.billet.origin(3)+dz];
            
            billetPointsF2 = [p2; p6; p7; p3; p2];
            billetPointsF4 = [p1; p4; p8; p5; p1];

            hold(app.UIAxes8, 'on');
            scatter(billetPointsF4(:,2), billetPointsF4(:,3), [], 'c','filled','Parent', app.UIAxes8);
%             fill(billetPointsF4(:,2), billetPointsF4(:,3), 'c','FaceAlpha', 0.5,'Parent', app.UIAxes8);

            scatter(app.stl.cutRootProfile(:,2), app.stl.cutRootProfile(:,3), [],...
                linspace(1, app.NoPointsEditField.Value-1, app.NoPointsEditField.Value-1), 'filled', 'Parent', app.UIAxes8);
            c = colorbar(app.UIAxes8);
            c.Ticks = [1, app.NoPointsEditField.Value-1];
            set(app.UIAxes8, 'XLim', [min(billetPointsF4(:,2))-buffL, max(billetPointsF4(:,2))+buffL]);
            set(app.UIAxes8, 'YLim', [min(billetPointsF4(:,3))-buffH, max(billetPointsF4(:,3))+buffH]);
            hold(app.UIAxes8, 'off');

            hold(app.UIAxes9, 'on');
            scatter(billetPointsF2(:,2), billetPointsF2(:,3), [], 'c','filled','Parent', app.UIAxes9);
%             fill(billetPointsF2(:,2), billetPointsF2(:,3), 'c','FaceAlpha', 0.5,'Parent', app.UIAxes9);
            
            scatter(app.stl.cutTipProfile(:,2), app.stl.cutTipProfile(:,3), [],...
                linspace(1, app.NoPointsEditField.Value-1, app.NoPointsEditField.Value-1), 'filled', 'Parent', app.UIAxes9);
            c = colorbar(app.UIAxes9);
            c.Ticks = [1, app.NoPointsEditField.Value-1];
            set(app.UIAxes9, 'XLim', [min(billetPointsF2(:,2))-buffL, max(billetPointsF2(:,2))+buffL]);
            set(app.UIAxes9, 'YLim', [min(billetPointsF2(:,3))-buffH, max(billetPointsF2(:,3))+buffH]);
            hold(app.UIAxes9, 'off');
        end

        % Button down function: UIAxes8
        function UIAxes8ButtonDown(app, event)
            selPt = app.UIAxes8.CurrentPoint;
%             disp([selPt(1,1), selPt(1,2)]);
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
%              SELECT POINT TO START CUTTING THE PROFILES
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %
            if strcmp(app.SelectStartPointSwitch.Value, 'Yes') == 1
                delete(findobj(app.UIAxes8, 'tag', 'startPtHighlight'))
                delete(findobj(app.UIAxes9, 'tag', 'startPtHighlight'))
                objs = findobj(app.UIAxes8, '-property', 'xdata');
                x = horzcat(objs.XData); 
                y = horzcat(objs.YData);  
                [~, idx] = min(hypot(x-selPt(1,1), y-selPt(1,2)));
                
                hold(app.UIAxes8, 'on')
                scatter(x(idx), y(idx), 110, 'red', 'filled','o', 'MarkerFaceAlpha', .4, 'MarkerFaceColor', 'red', 'MarkerEdgeColor','k', 'Tag', 'startPtHighlight', 'Parent', app.UIAxes8);
                drawnow()
                hold(app.UIAxes8, 'off')

                app.stl.idxOrder = linspace(1, app.NoPointsEditField.Value-1, app.NoPointsEditField.Value-1)';

                app.stl.idxOrder = circshift(app.stl.idxOrder, -(idx-1));
                if strcmp(app.DirectionOfCutDropDown.Value, app.gcode.oldDirectionOfCut) == 0
                    app.stl.idxOrder = [app.stl.idxOrder(1); flip(app.stl.idxOrder(2:end,:))];
                end

                app.stl.projRootProfile = app.stl.projRootProfile(app.stl.idxOrder,:);
                app.stl.projTipProfile  = app.stl.projTipProfile(app.stl.idxOrder,:); 
                app.stl.cutRootProfile  = app.stl.cutRootProfile(app.stl.idxOrder,:);
                app.stl.cutTipProfile   = app.stl.cutTipProfile(app.stl.idxOrder,:); 


                app.InsertProjectedProfilesButtonPushed(event);
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
%              SELECT POINT TO ENTRY THE BILLET
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %
            elseif strcmp(app.SelectEntryPointSwitch.Value, 'Yes') == 1
                delete(findobj(app.UIAxes8, 'tag', 'entryPtHighlight'))
                
                if strcmp(app.FoamEntryDropDown.Value, 'Select') == 1
                    app.WarningText.Value = 'Please, choose one of the two forms of foam entry from the drop-down menu.';
                elseif strcmp(app.FoamEntryDropDown.Value, 'Through') == 1
                    hold(app.UIAxes8, 'on')
                    scatter(selPt(1,1), selPt(1,2), 110, 'green', 'filled','o', 'MarkerFaceAlpha', .4, 'MarkerFaceColor', 'green', 'MarkerEdgeColor','k', 'Tag', 'entryPtHighlight', 'Parent', app.UIAxes8);
                    drawnow()                                        
                    hold(app.UIAxes8, 'off')
                    app.stl.cutEntryRootProfile  = [];

%                   Machine Home
                    app.stl.cutEntryRootProfile  = [app.stl.cutEntryRootProfile; [min(app.stl.cutRootProfile(:,1)),0,0]];

%                   Front face of billet
                    app.stl.cutEntryRootProfile  = [app.stl.cutEntryRootProfile; [min(app.stl.cutRootProfile(:,1)), app.billet.origin(2), app.billet.origin(3)+(app.billet.depth)/2]];

%                   In front of billet for wire heating up
                    bufferY = 10;  % mm
                    app.stl.cutEntryRootProfile = [app.stl.cutEntryRootProfile; [min(app.stl.cutRootProfile(:,1)), app.billet.origin(2)-bufferY, app.billet.origin(3)+(app.billet.depth)/2]];

%                   Entry point inside the billet
                    app.stl.cutEntryRootProfile = [app.stl.cutEntryRootProfile; [min(app.stl.cutRootProfile(:,1)), selPt(1,1), selPt(1,2)]];
                    
                elseif strcmp(app.FoamEntryDropDown.Value, 'Avoid') == 1
                    hold(app.UIAxes8, 'on')
                    scatter(selPt(1,1), selPt(1,2), 110, 'green', 'filled','o', 'MarkerFaceAlpha', .4, 'MarkerFaceColor', 'green', 'MarkerEdgeColor','k', 'Tag', 'entryPtHighlight', 'Parent', app.UIAxes8);
                    drawnow()                                        
                    hold(app.UIAxes8, 'off')
                    app.stl.cutEntryRootProfile  = [];

%                   Machine Home
                    app.stl.cutEntryRootProfile  = [app.stl.cutEntryRootProfile; [min(app.stl.cutRootProfile(:,1)),0,0]];

%                   Front face of billet
                    app.stl.cutEntryRootProfile  = [app.stl.cutEntryRootProfile; [min(app.stl.cutRootProfile(:,1)), app.billet.origin(2), app.billet.origin(3)+(app.billet.depth)/2]];

%                   In front of billet for wire heating up
                    bufferY = 10;  % mm
                    app.stl.cutEntryRootProfile = [app.stl.cutEntryRootProfile; [min(app.stl.cutRootProfile(:,1)), app.billet.origin(2)-bufferY, app.billet.origin(3)+(app.billet.depth)/2]];

%                   Optional foam saving
                    bufferZ = 70; % mm
                    app.stl.cutEntryRootProfile = [app.stl.cutEntryRootProfile; [min(app.stl.cutRootProfile(:,1)), app.billet.origin(2)-bufferY, app.billet.origin(3)+(app.billet.depth)+bufferZ]];
                    app.stl.cutEntryRootProfile = [app.stl.cutEntryRootProfile; [min(app.stl.cutRootProfile(:,1)), selPt(1,1), selPt(1,2)+bufferZ]];
                    app.stl.cutEntryRootProfile = [app.stl.cutEntryRootProfile; [min(app.stl.cutRootProfile(:,1)), selPt(1,1), selPt(1,2)]];
                end

                
                if strcmp(app.GeometryDropDown.Value, 'Straight') == 1
                    app.stl.cutEntryTipProfile      = app.stl.cutEntryRootProfile;
                    app.stl.cutEntryTipProfile(:,1) = max(app.stl.cutTipProfile(:,1));

                    delete(findobj(app.UIAxes9, 'tag', 'entryPtHighlight'));
                    hold(app.UIAxes9, 'on');
                    scatter(selPt(1,1), selPt(1,2), 110, 'green', 'filled','o', 'MarkerFaceAlpha', .4, 'MarkerFaceColor', 'green', 'MarkerEdgeColor','k', 'Tag', 'entryPtHighlight', 'Parent', app.UIAxes9);
                    drawnow();                                        
                    hold(app.UIAxes9, 'off')
                end

            elseif (strcmp(app.SelectEntryPointSwitch.Value, 'No') == 1 && strcmp(app.SelectStartPointSwitch.Value, 'No') == 1)
                app.WarningText.Value = 'If you want to re-select the Entry and Start points, please change either switches to the Option "Yes".';
            end
%             end
            app.gcode.oldDirectionOfCut = app.DirectionOfCutDropDown.Value;
        end

        % Button down function: UIAxes9
        function UIAxes9ButtonDown(app, event)
            selPt = app.UIAxes9.CurrentPoint;
%             disp([selPt(1,1), selPt(1,2)]);
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
%              SELECT POINT TO START CUTTING THE PROFILES
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %
            if strcmp(app.SelectStartPointSwitch.Value, 'Yes') == 1
                delete(findobj(app.UIAxes8, 'tag', 'startPtHighlight'))
                delete(findobj(app.UIAxes9, 'tag', 'startPtHighlight'))

                objs = findobj(app.UIAxes9, '-property', 'xdata');
                x = horzcat(objs.XData); 
                y = horzcat(objs.YData);  
                [~, idx] = min(hypot(x-selPt(1,1), y-selPt(1,2)));
                
                hold(app.UIAxes9, 'on')
                scatter(x(idx), y(idx), 110, 'red', 'filled','o', 'MarkerFaceAlpha', .4, 'MarkerFaceColor', 'red', 'MarkerEdgeColor','k', 'Tag', 'startPtHighlight', 'Parent', app.UIAxes9);
                drawnow()
                hold(app.UIAxes9, 'off')

                app.stl.idxOrder = linspace(1, app.NoPointsEditField.Value-1, app.NoPointsEditField.Value-1)';

                app.stl.idxOrder = circshift(app.stl.idxOrder, -(idx-1));
                if strcmp(app.DirectionOfCutDropDown.Value, app.gcode.oldDirectionOfCut) == 0
                    app.stl.idxOrder = [app.stl.idxOrder(1); flip(app.stl.idxOrder(2:end,:))];
                end


                app.stl.projRootProfile = app.stl.projRootProfile(app.stl.idxOrder,:);
                app.stl.projTipProfile  = app.stl.projTipProfile(app.stl.idxOrder,:); 
                app.stl.cutRootProfile = app.stl.cutRootProfile(app.stl.idxOrder,:);
                app.stl.cutTipProfile  = app.stl.cutTipProfile(app.stl.idxOrder,:); 

                
                app.InsertProjectedProfilesButtonPushed(event);
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
%              SELECT POINT TO ENTRY THE BILLET
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %
            elseif strcmp(app.SelectEntryPointSwitch.Value, 'Yes') == 1
                delete(findobj(app.UIAxes9, 'tag', 'entryPtHighlight'))

                if strcmp(app.FoamEntryDropDown.Value, 'Select') == 1
                    app.WarningText.Value = 'Please, choose one of the two forms of foam entry from the drop-down menu.';
                elseif strcmp(app.FoamEntryDropDown.Value, 'Through') == 1
                    hold(app.UIAxes9, 'on')
                    scatter(selPt(1,1), selPt(1,2), 110, 'green', 'filled','o', 'MarkerFaceAlpha', .4, 'MarkerFaceColor', 'green', 'MarkerEdgeColor','k', 'Tag', 'entryPtHighlight', 'Parent', app.UIAxes9);
                    drawnow()                                        
                    hold(app.UIAxes9, 'off')
                    app.stl.cutEntryTipProfile  = [];

%                   Machine Home
                    app.stl.cutEntryTipProfile  = [app.stl.cutEntryTipProfile; [max(app.stl.cutTipProfile(:,1)),0,0]];

%                   Front face of billet
                    app.stl.cutEntryTipProfile  = [app.stl.cutEntryTipProfile; [max(app.stl.cutTipProfile(:,1)), app.billet.origin(2), app.billet.origin(3)+(app.billet.depth)/2]];

%                   In front of billet for wire heating up
                    bufferY = 10;  % mm
                    app.stl.cutEntryTipProfile = [app.stl.cutEntryTipProfile; [max(app.stl.cutTipProfile(:,1)), app.billet.origin(2)-bufferY, app.billet.origin(3)+(app.billet.depth)/2]];

%                   Entry point inside the billet
                    app.stl.cutEntryTipProfile = [app.stl.cutEntryTipProfile; [max(app.stl.cutTipProfile(:,1)), selPt(1,1), selPt(1,2)]];
                    
                elseif strcmp(app.FoamEntryDropDown.Value, 'Avoid') == 1
                    hold(app.UIAxes9, 'on')
                    scatter(selPt(1,1), selPt(1,2), 110, 'green', 'filled','o', 'MarkerFaceAlpha', .4, 'MarkerFaceColor', 'green', 'MarkerEdgeColor','k', 'Tag', 'entryPtHighlight', 'Parent', app.UIAxes9);
                    drawnow()                                        
                    hold(app.UIAxes9, 'off')
                    app.stl.cutEntryTipProfile  = [];

%                   Machine Home
                    app.stl.cutEntryTipProfile  = [app.stl.cutEntryTipProfile; [max(app.stl.cutTipProfile(:,1)),0,0]];

%                   Front face of billet
                    app.stl.cutEntryTipProfile  = [app.stl.cutEntryTipProfile; [max(app.stl.cutTipProfile(:,1)), app.billet.origin(2), app.billet.origin(3)+(app.billet.depth)/2]];

%                   In front of billet for wire heating up
                    bufferY = 10;  % mm
                    app.stl.cutEntryTipProfile = [app.stl.cutEntryTipProfile; [max(app.stl.cutTipProfile(:,1)), app.billet.origin(2)-bufferY, app.billet.origin(3)+(app.billet.depth)/2]];

%                   Optional foam saving
                    bufferZ = 70; % mm
                    app.stl.cutEntryTipProfile = [app.stl.cutEntryTipProfile; [max(app.stl.cutTipProfile(:,1)), app.billet.origin(2)-bufferY, app.billet.origin(3)+(app.billet.depth)+bufferZ]];
                    app.stl.cutEntryTipProfile = [app.stl.cutEntryTipProfile; [max(app.stl.cutTipProfile(:,1)), selPt(1,1), selPt(1,2)+bufferZ]];
                    app.stl.cutEntryTipProfile = [app.stl.cutEntryTipProfile; [max(app.stl.cutTipProfile(:,1)), selPt(1,1), selPt(1,2)]];
                end

                if strcmp(app.GeometryDropDown.Value, 'Straight') == 1
                    app.stl.cutEntryRootProfile         = app.stl.cutEntryTipProfile;
                    app.stl.cutEntryRootProfile(:,1)    = min(app.stl.cutRootProfile(:,1));

                    delete(findobj(app.UIAxes8, 'tag', 'entryPtHighlight'));
                    hold(app.UIAxes8, 'on');
                    scatter(selPt(1,1), selPt(1,2), 110, 'green', 'filled','o', 'MarkerFaceAlpha', .4, 'MarkerFaceColor', 'green', 'MarkerEdgeColor','k', 'Tag', 'entryPtHighlight', 'Parent', app.UIAxes8);
                    drawnow();                                        
                    hold(app.UIAxes8, 'off')
                end

            elseif (strcmp(app.SelectEntryPointSwitch.Value, 'No') == 1 && strcmp(app.SelectStartPointSwitch.Value, 'No') == 1)
                app.WarningText.Value = 'If you want to re-select the Entry and Start points, please change either switches to the Option "Yes".';
            end
%             end
            app.gcode.oldDirectionOfCut = app.DirectionOfCutDropDown.Value;
        end

        % Value changed function: SelectStartPointSwitch
        function SelectStartPointSwitchValueChanged(app, event)
            if strcmp(app.SelectStartPointSwitch.Value, 'Yes') == 1
                app.SelectEntryPointSwitch.Value = 'No';
            end
        end

        % Value changed function: SelectEntryPointSwitch
        function SelectEntryPointSwitchValueChanged(app, event)
            if strcmp(app.SelectEntryPointSwitch.Value, 'Yes') == 1
                app.SelectStartPointSwitch.Value = 'No';
            end
        end

        % Value changed function: FoamEntryDropDown
        function FoamEntryDropDownValueChanged(app, event)
% % % % % % %           Entry point inside the billet
% % % % % %             dy = app.stl.projFullRootProfile(2,2)-app.stl.projFullRootProfile(1,2);
% % % % % %             dz = app.stl.projFullRootProfile(2,3)-app.stl.projFullRootProfile(1,3);
% % % % % %             faceNormalRight = [0, dz, -dy];
% % % % % %             faceNormalRight = faceNormalRight/norm(faceNormalRight);
% % % % % %             dy = app.stl.projFullRootProfile(end,2)-app.stl.projFullRootProfile(end-1,2);
% % % % % %             dz = app.stl.projFullRootProfile(end,3)-app.stl.projFullRootProfile(end-1,3);
% % % % % %             faceNormalLeft = [0, dz, -dy];
% % % % % %             faceNormalLeft = faceNormalLeft/norm(faceNormalLeft);
% % % % % % 
% % % % % %             vertexNormal = (faceNormalRight + faceNormalLeft)/2;
% % % % % %             
% % % % % %             frontRoot = [frontRoot; app.stl.projFullRootProfile(1,:) + vertexNormal*20];
% % % % % %             frontTip  = [frontTip;  app.stl.projFullTipProfile(1,:)  + vertexNormal*20];

%             if strcmp(app.FoamEntryDropDown.Value, 'Default')
% %               Add machine's home as first and last points 
%                 app.stl.projFullRootProfile = [[app.cnc.xyHeadPlaneX,0,0]; app.stl.projFullRootProfile; [app.cnc.xyHeadPlaneX,0,0]];
%                 app.stl.projFullTipProfile  = [[app.cnc.zaHeadPlaneX,0,0]; app.stl.projFullTipProfile;  [app.cnc.zaHeadPlaneX,0,0]];
% 
%                 app.billet.newOrigin = app.billet.origin;
%                 app.stl.newFaces     = app.stl.faces;
%                 app.stl.newVertices  = app.stl.vertices;
% 
%             elseif strcmp(app.FoamEntryDropDown.Value, 'Modified')
% %               Add billet's origin as first and last points
%                 app.stl.projFullRootProfile         = [[app.cnc.xyHeadPlaneX,app.billet.origin(2),app.billet.origin(3)]; app.stl.projFullRootProfile; [app.cnc.xyHeadPlaneX,app.billet.origin(2),app.billet.origin(3)]];
%                 app.stl.projFullTipProfile          = [[app.cnc.zaHeadPlaneX,app.billet.origin(2),app.billet.origin(3)]; app.stl.projFullTipProfile; [app.cnc.zaHeadPlaneX,app.billet.origin(2),app.billet.origin(3)]];
% %               Shift coordinate system (machine's) origin to billet's origin in the global XY  
%                 app.stl.projFullRootProfile(:,2)    = app.stl.projFullRootProfile(:,2) - app.billet.origin(2);
%                 app.stl.projFullRootProfile(:,3)    = app.stl.projFullRootProfile(:,3) - app.billet.origin(3);
%                 app.stl.projFullTipProfile(:,2)     = app.stl.projFullTipProfile(:,2) - app.billet.origin(2);
%                 app.stl.projFullTipProfile(:,3)     = app.stl.projFullTipProfile(:,3) - app.billet.origin(3);
% 
%                 app.stl.newPoints(1,:)                = app.stl.points(1,:) - app.billet.origin(1);
%                 app.stl.newPoints(2,:)                = app.stl.points(2,:) - app.billet.origin(2);
%                 app.stl.newPoints(3,:)                = app.stl.points(3,:) - app.billet.origin(3);
%                 [app.stl.newFaces, app.stl.newVertices]    = tetBoundaryFacets(app.stl.newPoints,app.stl.tetra(1:4,:));            
% 
%                 app.billet.newOrigin = [0, 0, 0];
%             end
        end

        % Button pushed function: SimulateCutButton
        function SimulateCutButtonPushed(app, event)
            app.WarningText.Value = '';
            
            app.UIAxes10.XLim = [app.billet.origin(1) - 100, app.billet.origin(1) + app.billet.width + 100];
            app.UIAxes10.YLim = [app.billet.origin(2) - 100, app.billet.origin(2) + app.billet.length + 100];
            app.UIAxes10.ZLim = [app.billet.origin(3) - 100, app.billet.origin(3) + app.billet.depth + 100];

            cla(app.UIAxes10);
            set(app.UIAxes10.Parent.Parent.Parent.Parent.Parent.Parent, 'renderer', 'opengl');
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %             
            dx = app.billet.width;
            dy = app.billet.length;
            dz = app.billet.depth;
            
            p1 = [app.billet.origin(1),      app.billet.origin(2),        app.billet.origin(3)];
            p2 = [app.billet.origin(1)+dx,   app.billet.origin(2),        app.billet.origin(3)];
            p3 = [app.billet.origin(1)+dx,   app.billet.origin(2),        app.billet.origin(3)+dz];
            p4 = [app.billet.origin(1),      app.billet.origin(2),        app.billet.origin(3)+dz];
            p5 = [app.billet.origin(1),      app.billet.origin(2)+dy,     app.billet.origin(3)];
            p6 = [app.billet.origin(1)+dx,   app.billet.origin(2)+dy,     app.billet.origin(3)];
            p7 = [app.billet.origin(1)+dx,   app.billet.origin(2)+dy,     app.billet.origin(3)+dz];
            p8 = [app.billet.origin(1),      app.billet.origin(2)+dy,     app.billet.origin(3)+dz];
            billetPointsF1 = [p1; p2; p3; p4];
            billetPointsF2 = [p2; p6; p7; p3];
            billetPointsF3 = [p6; p5; p8; p7];
            billetPointsF4 = [p1; p4; p8; p5];
            billetPointsF5 = [p4; p3; p7; p8];
            billetPointsF6 = [p1; p5; p6; p2];

            hold(app.UIAxes10, 'on');
            fill3(billetPointsF1(:,1), billetPointsF1(:,2), billetPointsF1(:,3), 'c',...
            billetPointsF2(:,1), billetPointsF2(:,2), billetPointsF2(:,3), 'c',...
            billetPointsF3(:,1), billetPointsF3(:,2), billetPointsF3(:,3), 'c',...
            billetPointsF4(:,1), billetPointsF4(:,2), billetPointsF4(:,3), 'c',...
            billetPointsF5(:,1), billetPointsF5(:,2), billetPointsF5(:,3), 'c',...
            billetPointsF6(:,1), billetPointsF6(:,2), billetPointsF6(:,3), 'c','FaceAlpha', 0.5,'Parent', app.UIAxes10);
            hold(app.UIAxes10, 'off');
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %    
            hold(app.UIAxes10, 'on');
            trisurf(app.stl.slicedMeshFaces, app.stl.slicedMeshVertices(:,1),app.stl.slicedMeshVertices(:,2),app.stl.slicedMeshVertices(:,3),'FaceColor','g', 'EdgeColor','k','FaceAlpha', 1, 'Clipping','off', 'Parent', app.UIAxes10);
            hold(app.UIAxes10, 'off');
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %   
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %   
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %   
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %   
            
            app.stl.projEntryRootProfile  = zeros(size(app.stl.cutEntryRootProfile));
            app.stl.projEntryTipProfile   = zeros(size(app.stl.cutEntryTipProfile));

            for i = 1:1:length(app.stl.cutEntryRootProfile)
                app.stl.projEntryRootProfile(i,1) = app.cnc.xyHeadPlaneX;
                app.stl.projEntryTipProfile(i,1)  = app.cnc.zaHeadPlaneX;
    
                xzProj                            = polyfit([app.stl.cutEntryRootProfile(i,1), app.stl.cutEntryTipProfile(i,1)], [app.stl.cutEntryRootProfile(i,2), app.stl.cutEntryTipProfile(i,2)], 1);
                app.stl.projEntryRootProfile(i,2) = xzProj(1)*app.cnc.xyHeadPlaneX + xzProj(2);
                app.stl.projEntryTipProfile(i,2)  = xzProj(1)*app.cnc.zaHeadPlaneX + xzProj(2);
    
                yaProj                              = polyfit([app.stl.cutEntryRootProfile(i,1), app.stl.cutEntryTipProfile(i,1)], [app.stl.cutEntryRootProfile(i,3), app.stl.cutEntryTipProfile(i,3)], 1);
                app.stl.projEntryRootProfile(i,3) = yaProj(1)*app.cnc.xyHeadPlaneX + yaProj(2);
                app.stl.projEntryTipProfile(i,3)  = yaProj(1)*app.cnc.zaHeadPlaneX + yaProj(2);    
            end
            
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %   
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %   
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %   
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %   
            app.stl.projFullRootProfile = [];
            app.stl.projFullTipProfile  = [];
            app.stl.projFullRootProfile = [app.stl.projEntryRootProfile; app.stl.projRootProfile; app.stl.projRootProfile(1,:); app.stl.projEntryRootProfile(end:-1:1,:)];
            app.stl.projFullTipProfile  = [app.stl.projEntryTipProfile;  app.stl.projTipProfile;  app.stl.projTipProfile(1,:); app.stl.projEntryTipProfile(end:-1:1,:)];
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %   
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %   
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %   
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %   
            delete(findobj(app.UIAxes10, 'tag', 'cutRoot'));
            delete(findobj(app.UIAxes10, 'tag', 'cutTip'));

            hold(app.UIAxes10, "on");
            rootProfileX = [];
            rootProfileY = [];
            rootProfileZ = [];
            tipProfileX  = [];
            tipProfileY  = [];
            tipProfileZ  = [];
            for i = 1:1:length(app.stl.projFullRootProfile)
                rootProfileX = [rootProfileX; app.billet.origin(1)];
                tipProfileX  = [tipProfileX; (app.billet.origin(1) + app.billet.width)];
    
                xzProj       = polyfit([app.stl.projFullRootProfile(i,1), app.stl.projFullTipProfile(i,1)], [app.stl.projFullRootProfile(i,2), app.stl.projFullTipProfile(i,2)], 1);
                rootProfileY = [rootProfileY; xzProj(1)*rootProfileX(end)+ xzProj(2)];
                tipProfileY  = [tipProfileY; xzProj(1)*tipProfileX(end)+ xzProj(2)];
    
                yaProj       = polyfit([app.stl.projFullRootProfile(i,1), app.stl.projFullTipProfile(i,1)], [app.stl.projFullRootProfile(i,3), app.stl.projFullTipProfile(i,3)], 1);
                rootProfileZ = [rootProfileZ; yaProj(1)*rootProfileX(end) + yaProj(2)];
                tipProfileZ  = [tipProfileZ; yaProj(1)*tipProfileX(end) + yaProj(2)];    
                
                plot3(app.UIAxes10,rootProfileX,rootProfileY,rootProfileZ, '-ro', 'markerfacecolor', 'r', 'markeredgecolor', 'r', 'markersize', 5, 'linewidth', 1, 'Tag', 'cutRoot');
                plot3(app.UIAxes10,tipProfileX,tipProfileY,tipProfileZ, '-bo', 'markerfacecolor', 'b', 'markeredgecolor', 'b', 'markersize', 5, 'linewidth', 1, 'Tag', 'cutTip');

                plot3(app.UIAxes10,[app.stl.projFullRootProfile(i,1), app.stl.projFullTipProfile(i,1)],[app.stl.projFullRootProfile(i,2), app.stl.projFullTipProfile(i,2)],[app.stl.projFullRootProfile(i,3), app.stl.projFullTipProfile(i,3)], '-', 'color', 'k', 'linewidth', 1, 'Tag', 'wire');
                
                pause(0.1);      
                delete(findobj(app.UIAxes10, 'tag', 'wire'));
            end
            hold(app.UIAxes10, "off");
    
            app.stl.cutFullRootProfile = [rootProfileX, rootProfileY, rootProfileZ];
            app.stl.cutFullTipProfile  = [tipProfileX, tipProfileY, tipProfileZ];
        end

        % Button pushed function: GenerateGcodeButton
        function GenerateGcodeButtonPushed(app, event)
            app.TextArea_5.Value = 'Loading...';

            app.gcode.numOfLines    = length(app.stl.projFullRootProfile) + 18;
            app.gcode.code          = cell(app.gcode.numOfLines,1);
            
            app.gcode.code{1} = ['%%G-code: ', app.gcode.fileName];
            app.gcode.code{2} = '%%------------------ PROGRAM SETTING ------------------';
            app.gcode.code{3} = 'G21    %%metric units';
            app.gcode.code{4} = 'G94    %%millimeters per minute feedrate';
            app.gcode.code{5} = 'G90    %%absolute distance mode w.r.t active coord. sys.';
           
            app.gcode.code{6} = '%%------------------ FOAM LOADING ------------------';
            app.gcode.code{7} = ['G0', ' X ', num2str(app.stl.projFullRootProfile(1,2),'%5.5f'), ' Y ', num2str(app.stl.projFullRootProfile(1,3), '%5.5f'),...
                                        ' Z ', num2str(app.stl.projFullTipProfile(1,2), '%5.5f'), ' A ', num2str(app.stl.projFullTipProfile(1,3), '%5.5f'), '   %%machine home'];
            app.gcode.code{8} = ['G0', ' X ', num2str(app.stl.projFullRootProfile(2,2),'%5.5f'), ' Y ', num2str(app.stl.projFullRootProfile(2,3), '%5.5f'),...
                                       ' Z ', num2str(app.stl.projFullTipProfile(2,2), '%5.5f'), ' A ', num2str(app.stl.projFullTipProfile(2,3), '%5.5f'), '    %%front of billet'];
            app.gcode.code{9} = 'M1     %%program temporary stop';

            app.gcode.code{10} = '%%LOAD BILLET IN THE MACHINE AND PRESS CYCLE START (GREEN BUTTON) WHEN READY.';
            app.gcode.code{11} = '%%Hot wire should be at middle depth of the billet. If your part is tapered, pay special attention to the billet position in between heads.'; 
            
            app.gcode.code{12} = '%%------------------ CUT PREPARATION ------------------';
            if strcmp(app.FoamEntryDropDown.Value, 'Through') == 1
                app.gcode.code{13} = ['G0', ' X ', num2str(app.stl.projFullRootProfile(3,2),'%5.5f'), ' Y ', num2str(app.stl.projFullRootProfile(3,3), '%5.5f'),...
                                        ' Z ', num2str(app.stl.projFullTipProfile(3,2), '%5.5f'), ' A ', num2str(app.stl.projFullTipProfile(3,3), '%5.5f')];
                
                app.gcode.code{14} = 'M1     %%program temporary stop';
                app.gcode.code{15} = '%%TURN ON THE HOT WIRE CURRENT/VOLTAGE AND PRESS CYCLE START (GREEN BUTTON) WHEN READY.';
                app.gcode.code{16} = 'G4 P5';
                app.gcode.code{17} = '%%hot wire heating up...';
    
                app.gcode.code{18} = '%%------------------ CUTTING ------------------';
                app.gcode.code{19} = '%%starting cut...';
            
                j = 16;

                for i = 4:1:length(app.stl.projFullRootProfile)-3
                    app.gcode.code{j+i,1} = ['G01 F', num2str(app.FeedRateEditField.Value),...
                        ' X ', num2str(app.stl.projFullRootProfile(i,2),'%5.5f'), ' Y ', num2str(app.stl.projFullRootProfile(i,3), '%5.5f'),...
                        ' Z ', num2str(app.stl.projFullTipProfile(i,2), '%5.5f'), ' A ', num2str(app.stl.projFullTipProfile(i,3), '%5.5f')];
                end
                
                app.gcode.code{j+i+1,1} = ['G0 F', num2str(app.FeedRateEditField.Value),...
                        ' X ', num2str(app.stl.projFullRootProfile(i+1,2),'%5.5f'), ' Y ', num2str(app.stl.projFullRootProfile(i+1,3), '%5.5f'),...
                        ' Z ', num2str(app.stl.projFullTipProfile(i+1,2), '%5.5f'), ' A ', num2str(app.stl.projFullTipProfile(i+1,3), '%5.5f')];
                app.gcode.code{j+i+2,1} = ['G0 F', num2str(app.FeedRateEditField.Value),...
                                    ' X ', num2str(app.stl.projFullRootProfile(i+2,2),'%5.5f'), ' Y ', num2str(app.stl.projFullRootProfile(i+2,3), '%5.5f'),...
                                    ' Z ', num2str(app.stl.projFullTipProfile(i+2,2), '%5.5f'), ' A ', num2str(app.stl.projFullTipProfile(i+2,3), '%5.5f')];
                app.gcode.code{j+i+3,1} = ['G0 F', num2str(app.FeedRateEditField.Value),...
                                    ' X ', num2str(app.stl.projFullRootProfile(i+3,2),'%5.5f'), ' Y ', num2str(app.stl.projFullRootProfile(i+3,3), '%5.5f'),...
                                    ' Z ', num2str(app.stl.projFullTipProfile(i+3,2), '%5.5f'), ' A ', num2str(app.stl.projFullTipProfile(i+3,3), '%5.5f')];
    
                app.gcode.code{j+i+4,1} = '%%ending cut...';

            elseif strcmp(app.FoamEntryDropDown.Value, 'Avoid') == 1
                app.gcode.code{13} = ['G0', ' X ', num2str(app.stl.projFullRootProfile(3,2),'%5.5f'), ' Y ', num2str(app.stl.projFullRootProfile(3,3), '%5.5f'),...
                                        ' Z ', num2str(app.stl.projFullTipProfile(3,2), '%5.5f'), ' A ', num2str(app.stl.projFullTipProfile(3,3), '%5.5f')];                
                app.gcode.code{14} = ['G0', ' X ', num2str(app.stl.projFullRootProfile(4,2),'%5.5f'), ' Y ', num2str(app.stl.projFullRootProfile(4,3), '%5.5f'),...
                                        ' Z ', num2str(app.stl.projFullTipProfile(4,2), '%5.5f'), ' A ', num2str(app.stl.projFullTipProfile(4,3), '%5.5f')];                
                app.gcode.code{15} = ['G0', ' X ', num2str(app.stl.projFullRootProfile(5,2),'%5.5f'), ' Y ', num2str(app.stl.projFullRootProfile(5,3), '%5.5f'),...
                                        ' Z ', num2str(app.stl.projFullTipProfile(5,2), '%5.5f'), ' A ', num2str(app.stl.projFullTipProfile(5,3), '%5.5f')];                

                app.gcode.code{16} = 'M1     %%program temporary stop';
                app.gcode.code{17} = '%%TURN ON THE HOT WIRE CURRENT/VOLTAGE AND PRESS CYCLE START (GREEN BUTTON) WHEN READY.';
                app.gcode.code{18} = 'G4 P5';
                app.gcode.code{19} = '%%hot wire heating up...';
    
                app.gcode.code{20} = '%%------------------ CUTTING ------------------';
                app.gcode.code{21} = '%%starting cut...';

                j = 16;

                for i = 6:1:length(app.stl.projFullRootProfile)-5
                    app.gcode.code{j+i,1} = ['G01 F', num2str(app.FeedRateEditField.Value),...
                        ' X ', num2str(app.stl.projFullRootProfile(i,2),'%5.5f'), ' Y ', num2str(app.stl.projFullRootProfile(i,3), '%5.5f'),...
                        ' Z ', num2str(app.stl.projFullTipProfile(i,2), '%5.5f'), ' A ', num2str(app.stl.projFullTipProfile(i,3), '%5.5f')];
                end
    

                app.gcode.code{j+i+1,1} = ['G0 F', num2str(app.FeedRateEditField.Value),...
                                    ' X ', num2str(app.stl.projFullRootProfile(i+1,2),'%5.5f'), ' Y ', num2str(app.stl.projFullRootProfile(i+1,3), '%5.5f'),...
                                    ' Z ', num2str(app.stl.projFullTipProfile(i+1,2), '%5.5f'), ' A ', num2str(app.stl.projFullTipProfile(i+1,3), '%5.5f')];
                app.gcode.code{j+i+2,1} = ['G0 F', num2str(app.FeedRateEditField.Value),...
                                    ' X ', num2str(app.stl.projFullRootProfile(i+2,2),'%5.5f'), ' Y ', num2str(app.stl.projFullRootProfile(i+2,3), '%5.5f'),...
                                    ' Z ', num2str(app.stl.projFullTipProfile(i+2,2), '%5.5f'), ' A ', num2str(app.stl.projFullTipProfile(i+2,3), '%5.5f')];
                app.gcode.code{j+i+3,1} = ['G0 F', num2str(app.FeedRateEditField.Value),...
                                    ' X ', num2str(app.stl.projFullRootProfile(i+3,2),'%5.5f'), ' Y ', num2str(app.stl.projFullRootProfile(i+3,3), '%5.5f'),...
                                    ' Z ', num2str(app.stl.projFullTipProfile(i+3,2), '%5.5f'), ' A ', num2str(app.stl.projFullTipProfile(i+3,3), '%5.5f')];
                app.gcode.code{j+i+4,1} = ['G0 F', num2str(app.FeedRateEditField.Value),...
                                    ' X ', num2str(app.stl.projFullRootProfile(i+4,2),'%5.5f'), ' Y ', num2str(app.stl.projFullRootProfile(i+4,3), '%5.5f'),...
                                    ' Z ', num2str(app.stl.projFullTipProfile(i+4,2), '%5.5f'), ' A ', num2str(app.stl.projFullTipProfile(i+4,3), '%5.5f')];
                app.gcode.code{j+i+5,1} = ['G0 F', num2str(app.FeedRateEditField.Value),...
                                    ' X ', num2str(app.stl.projFullRootProfile(i+5,2),'%5.5f'), ' Y ', num2str(app.stl.projFullRootProfile(i+5,3), '%5.5f'),...
                                    ' Z ', num2str(app.stl.projFullTipProfile(i+5,2), '%5.5f'), ' A ', num2str(app.stl.projFullTipProfile(i+5,3), '%5.5f')];
                app.gcode.code{j+i+6,1} = '%ending cut...';
            end


            app.gcode.code{end} = 'M2 %%program end';
%             keyboard;
            app.TextArea_5.Value = app.gcode.code;

            app.Slider.Limits = [0, app.gcode.numOfLines];
            app.Slider.MajorTicks = linspace(0, app.gcode.numOfLines, app.gcode.numOfLines+1);
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
            cla(app.UIAxes11);
            set(app.UIAxes11.Parent.Parent.Parent.Parent.Parent.Parent, 'renderer', 'opengl');
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
            dx = app.cnc.bedW;            
            dy = app.cnc.bedL;
            dz = app.cnc.bedD;
            p1 = [app.cnc.bedShiftX,    app.cnc.bedShiftY,      app.cnc.bedShiftZ];
            p2 = [app.cnc.bedShiftX+dx, app.cnc.bedShiftY,      app.cnc.bedShiftZ];
            p3 = [app.cnc.bedShiftX+dx, app.cnc.bedShiftY,      dz];
            p4 = [app.cnc.bedShiftX,    app.cnc.bedShiftY,      dz];
            p5 = [app.cnc.bedShiftX,    app.cnc.bedShiftY+dy,   app.cnc.bedShiftZ];
            p6 = [app.cnc.bedShiftX+dx, app.cnc.bedShiftY+dy,   app.cnc.bedShiftZ];
            p7 = [app.cnc.bedShiftX+dx, app.cnc.bedShiftY+dy,   dz];
            p8 = [app.cnc.bedShiftX,    app.cnc.bedShiftY+dy,   dz];
            cncPointsF1 = [p1; p2; p3; p4];
            cncPointsF2 = [p2; p6; p7; p3];
            cncPointsF3 = [p6; p5; p8; p7];
            cncPointsF4 = [p1; p4; p8; p5];
            cncPointsF5 = [p4; p3; p7; p8];
            cncPointsF6 = [p1; p5; p6; p2];

            fill3(cncPointsF1(:,1), cncPointsF1(:,2), cncPointsF1(:,3), 'c',...
            cncPointsF2(:,1), cncPointsF2(:,2), cncPointsF2(:,3), 'c',...
            cncPointsF3(:,1), cncPointsF3(:,2), cncPointsF3(:,3), 'c',...
            cncPointsF4(:,1), cncPointsF4(:,2), cncPointsF4(:,3), 'c',...
            cncPointsF5(:,1), cncPointsF5(:,2), cncPointsF5(:,3), 'c',...
            cncPointsF6(:,1), cncPointsF6(:,2), cncPointsF6(:,3), 'c','FaceAlpha', 1,'Parent', app.UIAxes11);
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
            hold(app.UIAxes11, 'on');
            dx = app.cnc.cutW;            
            dy = app.cnc.cutL;
            dz = app.cnc.cutD;
            p1 = [app.cnc.cutShiftX,    app.cnc.cutShiftY,      app.cnc.cutShiftZ];
            p2 = [app.cnc.cutShiftX+dx, app.cnc.cutShiftY,      app.cnc.cutShiftZ];
            p3 = [app.cnc.cutShiftX+dx, app.cnc.cutShiftY,      app.cnc.cutShiftZ+dz];
            p4 = [app.cnc.cutShiftX,    app.cnc.cutShiftY,      app.cnc.cutShiftZ+dz];
            p5 = [app.cnc.cutShiftX,    app.cnc.cutShiftY+dy,   app.cnc.cutShiftZ];
            p6 = [app.cnc.cutShiftX+dx, app.cnc.cutShiftY+dy,   app.cnc.cutShiftZ];
            p7 = [app.cnc.cutShiftX+dx, app.cnc.cutShiftY+dy,   app.cnc.cutShiftZ+dz];
            p8 = [app.cnc.cutShiftX,    app.cnc.cutShiftY+dy,   app.cnc.cutShiftZ+dz];
            cncPointsF1 = [p1; p2; p3; p4];
            cncPointsF2 = [p2; p6; p7; p3];
            cncPointsF3 = [p6; p5; p8; p7];
            cncPointsF4 = [p1; p4; p8; p5];
            cncPointsF5 = [p4; p3; p7; p8];
            cncPointsF6 = [p1; p5; p6; p2];

            fill3(cncPointsF1(:,1), cncPointsF1(:,2), cncPointsF1(:,3), 'w',...
            cncPointsF2(:,1), cncPointsF2(:,2), cncPointsF2(:,3), 'w',...
            cncPointsF3(:,1), cncPointsF3(:,2), cncPointsF3(:,3), 'w',...
            cncPointsF4(:,1), cncPointsF4(:,2), cncPointsF4(:,3), 'w',...
            cncPointsF5(:,1), cncPointsF5(:,2), cncPointsF5(:,3), 'w',...
            cncPointsF6(:,1), cncPointsF6(:,2), cncPointsF6(:,3), 'w','FaceAlpha', 0.05,'Parent', app.UIAxes11);
            hold(app.UIAxes11, 'off');
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % %             
            hold(app.UIAxes11, 'on');
            dx = app.cnc.boxW;            
            dy = app.cnc.boxL;
            dz = app.cnc.boxD;
            p1 = [app.cnc.boxShiftX,    app.cnc.boxShiftY,      app.cnc.boxShiftZ];
            p2 = [app.cnc.boxShiftX+dx, app.cnc.boxShiftY,      app.cnc.boxShiftZ];
            p3 = [app.cnc.boxShiftX+dx, app.cnc.boxShiftY,      app.cnc.boxShiftZ+dz];
            p4 = [app.cnc.boxShiftX,    app.cnc.boxShiftY,      app.cnc.boxShiftZ+dz];
            p5 = [app.cnc.boxShiftX,    app.cnc.boxShiftY+dy,   app.cnc.boxShiftZ];
            p6 = [app.cnc.boxShiftX+dx, app.cnc.boxShiftY+dy,   app.cnc.boxShiftZ];
            p7 = [app.cnc.boxShiftX+dx, app.cnc.boxShiftY+dy,   app.cnc.boxShiftZ+dz];
            p8 = [app.cnc.boxShiftX,    app.cnc.boxShiftY+dy,   app.cnc.boxShiftZ+dz];
            cncPointsF1 = [p1; p2; p3; p4];
            cncPointsF2 = [p2; p6; p7; p3];
            cncPointsF3 = [p6; p5; p8; p7];
            cncPointsF4 = [p1; p4; p8; p5];
            cncPointsF5 = [p4; p3; p7; p8];
            cncPointsF6 = [p1; p5; p6; p2];

            fill3(cncPointsF1(:,1), cncPointsF1(:,2), cncPointsF1(:,3), 'w',...
            cncPointsF2(:,1), cncPointsF2(:,2), cncPointsF2(:,3), 'w',...
            cncPointsF3(:,1), cncPointsF3(:,2), cncPointsF3(:,3), 'w',...
            cncPointsF4(:,1), cncPointsF4(:,2), cncPointsF4(:,3), 'w',...
            cncPointsF5(:,1), cncPointsF5(:,2), cncPointsF5(:,3), 'w',...
            cncPointsF6(:,1), cncPointsF6(:,2), cncPointsF6(:,3), 'w','FaceAlpha', 0.05,'Parent', app.UIAxes11);
            hold(app.UIAxes11, 'off');
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
            hold(app.UIAxes11, 'on');
            xyHeadPlane.vertices = [    app.cnc.xyHeadPlaneX, 0,            0;
                                        app.cnc.xyHeadPlaneX, 0,            app.cnc.cutD;
                                        app.cnc.xyHeadPlaneX, app.cnc.cutL, app.cnc.cutD;
                                        app.cnc.xyHeadPlaneX, app.cnc.cutL, 0];
            xyHeadPlane.faces    = [    1, 2, 3;
                                        1, 3, 4];
            p1 = patch(app.UIAxes11, xyHeadPlane.vertices(:,1), xyHeadPlane.vertices(:,2), xyHeadPlane.vertices(:,3), 'r');
            p1.FaceVertexAlphaData  = 0.05;
            p1.FaceAlpha            = 'flat' ;
            
            zaHeadPlane.vertices = [    app.cnc.zaHeadPlaneX, 0,            0;
                                        app.cnc.zaHeadPlaneX, 0,            app.cnc.cutD;
                                        app.cnc.zaHeadPlaneX, app.cnc.cutL, app.cnc.cutD;
                                        app.cnc.zaHeadPlaneX, app.cnc.cutL, 0];
            zaHeadPlane.faces    = [    1, 2, 3;
                                        1, 3, 4];
            p2 = patch(app.UIAxes11, zaHeadPlane.vertices(:,1), zaHeadPlane.vertices(:,2), zaHeadPlane.vertices(:,3), 'b');
            p2.FaceVertexAlphaData  = 0.05;
            p2.FaceAlpha            = 'flat' ;
            hold(app.UIAxes11, 'off');
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
            dx = app.billet.width;
            dy = app.billet.length;
            dz = app.billet.depth;
            
            p1 = [app.billet.origin(1),      app.billet.origin(2),        app.billet.origin(3)];
            p2 = [app.billet.origin(1)+dx,   app.billet.origin(2),        app.billet.origin(3)];
            p3 = [app.billet.origin(1)+dx,   app.billet.origin(2),        app.billet.origin(3)+dz];
            p4 = [app.billet.origin(1),      app.billet.origin(2),        app.billet.origin(3)+dz];
            p5 = [app.billet.origin(1),      app.billet.origin(2)+dy,     app.billet.origin(3)];
            p6 = [app.billet.origin(1)+dx,   app.billet.origin(2)+dy,     app.billet.origin(3)];
            p7 = [app.billet.origin(1)+dx,   app.billet.origin(2)+dy,     app.billet.origin(3)+dz];
            p8 = [app.billet.origin(1),      app.billet.origin(2)+dy,     app.billet.origin(3)+dz];
            billetPointsF1 = [p1; p2; p3; p4];
            billetPointsF2 = [p2; p6; p7; p3];
            billetPointsF3 = [p6; p5; p8; p7];
            billetPointsF4 = [p1; p4; p8; p5];
            billetPointsF5 = [p4; p3; p7; p8];
            billetPointsF6 = [p1; p5; p6; p2];

            hold(app.UIAxes11, 'on');
            fill3(billetPointsF1(:,1), billetPointsF1(:,2), billetPointsF1(:,3), 'c',...
            billetPointsF2(:,1), billetPointsF2(:,2), billetPointsF2(:,3), 'c',...
            billetPointsF3(:,1), billetPointsF3(:,2), billetPointsF3(:,3), 'c',...
            billetPointsF4(:,1), billetPointsF4(:,2), billetPointsF4(:,3), 'c',...
            billetPointsF5(:,1), billetPointsF5(:,2), billetPointsF5(:,3), 'c',...
            billetPointsF6(:,1), billetPointsF6(:,2), billetPointsF6(:,3), 'c','FaceAlpha', 0.5,'Parent', app.UIAxes11);
            hold(app.UIAxes11, 'off');
        end

        % Button pushed function: RunGcodeButton
        function RunGcodeButtonPushed(app, event)
% % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % % 
            app.GenerateGcodeButtonPushed(event);         

            hold(app.UIAxes11, 'on');
            j = 0;
            app.gcode.codeToDisplay = app.gcode.code;
            for i = 1:1:app.gcode.numOfLines
                if strcmp(app.gcode.codeToDisplay{i}(1:2), 'G0') == 1
                    j = j + 1;
                    tmp          = app.gcode.codeToDisplay{i};
                    app.gcode.codeToDisplay{i} = ['>>>>>>>>>>>>> ', tmp];
                    app.TextArea_5.Value = app.gcode.codeToDisplay;
                    if j == 1 
                        plot3(app.UIAxes11,app.stl.cutFullRootProfile(j,1),app.stl.cutFullRootProfile(j,2),app.stl.cutFullRootProfile(j,3), '-r', 'markerfacecolor', 'r', 'markeredgecolor', 'r', 'markersize', 5, 'linewidth', 1, 'Tag', 'cutRoot');
                        plot3(app.UIAxes11,app.stl.cutFullTipProfile(j,1),app.stl.cutFullTipProfile(j,2),app.stl.cutFullTipProfile(j,3), '-b', 'markerfacecolor', 'b', 'markeredgecolor', 'b', 'markersize', 5, 'linewidth', 1, 'Tag', 'cutTip');
    
                        plot3(app.UIAxes11,app.stl.projFullRootProfile(j,1),app.stl.projFullRootProfile(j,2),app.stl.projFullRootProfile(j,3), '-r', 'markerfacecolor', 'r', 'markeredgecolor', 'r', 'markersize', 5, 'linewidth', 1, 'Tag', 'projRoot');
                        plot3(app.UIAxes11,app.stl.projFullTipProfile(j,1),app.stl.projFullTipProfile(j,2),app.stl.projFullTipProfile(j,3), '-b', 'markerfacecolor', 'b', 'markeredgecolor', 'b', 'markersize', 5, 'linewidth', 1, 'Tag', 'projTip');
                        pause(0.1);      
                    else
                        plot3(app.UIAxes11,[app.stl.cutFullRootProfile(j,1),app.stl.cutFullRootProfile(j-1,1)],[app.stl.cutFullRootProfile(j,2), app.stl.cutFullRootProfile(j-1,2)],[app.stl.cutFullRootProfile(j,3),app.stl.cutFullRootProfile(j-1,3)], '-r', 'markerfacecolor', 'r', 'markeredgecolor', 'r', 'markersize', 5, 'linewidth', 1, 'Tag', 'cutRoot');
                        plot3(app.UIAxes11,[app.stl.cutFullTipProfile(j,1),app.stl.cutFullTipProfile(j-1,1)],[app.stl.cutFullTipProfile(j,2),app.stl.cutFullTipProfile(j-1,2)],[app.stl.cutFullTipProfile(j,3),app.stl.cutFullTipProfile(j-1,3)], '-b', 'markerfacecolor', 'b', 'markeredgecolor', 'b', 'markersize', 5, 'linewidth', 1, 'Tag', 'cutTip');
                        
                        plot3(app.UIAxes11,[app.stl.projFullRootProfile(j,1),app.stl.projFullRootProfile(j-1,1)],[app.stl.projFullRootProfile(j,2), app.stl.projFullRootProfile(j-1,2)],[app.stl.projFullRootProfile(j,3),app.stl.projFullRootProfile(j-1,3)], '-r', 'markerfacecolor', 'r', 'markeredgecolor', 'r', 'markersize', 5, 'linewidth', 1, 'Tag', 'projRoot');
                        plot3(app.UIAxes11,[app.stl.projFullTipProfile(j,1),app.stl.projFullTipProfile(j-1,1)],[app.stl.projFullTipProfile(j,2),app.stl.projFullTipProfile(j-1,2)],[app.stl.projFullTipProfile(j,3),app.stl.projFullTipProfile(j-1,3)], '-b', 'markerfacecolor', 'b', 'markeredgecolor', 'b', 'markersize', 5, 'linewidth', 1, 'Tag', 'projTip');
                        pause(0.1);      
                    end
                    app.gcode.codeToDisplay{i} = tmp;
                    app.TextArea_5.Value = app.gcode.codeToDisplay;
                else 
                    tmp          = app.gcode.codeToDisplay{i};
                    app.gcode.codeToDisplay{i} = ['>>>>>>>>>>>>> ', tmp];
                    app.TextArea_5.Value = app.gcode.codeToDisplay;
                    pause(0.1); 
                    app.gcode.codeToDisplay{i} = tmp;
                    app.TextArea_5.Value = app.gcode.codeToDisplay;
                end
            end
            hold(app.UIAxes11, 'off');

            delete(findobj(app.UIAxes11, 'tag', 'cutRoot'));
            delete(findobj(app.UIAxes11, 'tag', 'cutTip'));
            delete(findobj(app.UIAxes11, 'tag', 'projRoot'));
            delete(findobj(app.UIAxes11, 'tag', 'projTip'));
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.GenerateGcodeButtonPushed(event);

            value               = app.Slider.Value;
            [~, idx]            = min(abs(value - app.Slider.MajorTicks(:)));
            app.Slider.Value    = app.Slider.MajorTicks(idx);
    
            lineToDisplay       = fix(app.Slider.Value);

            if lineToDisplay == 0
                app.GenerateGcodeButtonPushed(event);
            else
                app.gcode.codeToDisplay                 = app.gcode.code;
                app.gcode.codeToDisplay{lineToDisplay}  = ['>>>>>>>>>>>>> ', app.gcode.codeToDisplay{lineToDisplay}];
                app.TextArea_5.Value                    = app.gcode.codeToDisplay;                    
                
                hold(app.UIAxes11, 'on');
                i = 0;
                for j = 1:1:lineToDisplay
                    if strcmp(app.gcode.code{j}(1:2), 'G0') == 1
                        i = i + 1; 
                        if i == 1
                            plot3(app.UIAxes11,app.stl.cutFullRootProfile(i,1),app.stl.cutFullRootProfile(i,2),app.stl.cutFullRootProfile(i,3), '-r', 'markerfacecolor', 'r', 'markeredgecolor', 'r', 'markersize', 5, 'linewidth', 1, 'Tag', 'cutRoot');
                            plot3(app.UIAxes11,app.stl.cutFullTipProfile(i,1),app.stl.cutFullTipProfile(i,2),app.stl.cutFullTipProfile(i,3), '-b', 'markerfacecolor', 'b', 'markeredgecolor', 'b', 'markersize', 5, 'linewidth', 1, 'Tag', 'cutTip');
        
                            plot3(app.UIAxes11,app.stl.projFullRootProfile(i,1),app.stl.projFullRootProfile(i,2),app.stl.projFullRootProfile(i,3), '-r', 'markerfacecolor', 'r', 'markeredgecolor', 'r', 'markersize', 5, 'linewidth', 1, 'Tag', 'projRoot');
                            plot3(app.UIAxes11,app.stl.projFullTipProfile(i,1),app.stl.projFullTipProfile(i,2),app.stl.projFullTipProfile(i,3), '-b', 'markerfacecolor', 'b', 'markeredgecolor', 'b', 'markersize', 5, 'linewidth', 1, 'Tag', 'projTip');   
                        else
                            plot3(app.UIAxes11,[app.stl.cutFullRootProfile(i,1),app.stl.cutFullRootProfile(i-1,1)],[app.stl.cutFullRootProfile(i,2), app.stl.cutFullRootProfile(i-1,2)],[app.stl.cutFullRootProfile(i,3),app.stl.cutFullRootProfile(i-1,3)], '-r', 'markerfacecolor', 'r', 'markeredgecolor', 'r', 'markersize', 5, 'linewidth', 1, 'Tag', 'cutRoot');
                            plot3(app.UIAxes11,[app.stl.cutFullTipProfile(i,1),app.stl.cutFullTipProfile(i-1,1)],[app.stl.cutFullTipProfile(i,2),app.stl.cutFullTipProfile(i-1,2)],[app.stl.cutFullTipProfile(i,3),app.stl.cutFullTipProfile(i-1,3)], '-b', 'markerfacecolor', 'b', 'markeredgecolor', 'b', 'markersize', 5, 'linewidth', 1, 'Tag', 'cutTip');
                            
                            plot3(app.UIAxes11,[app.stl.projFullRootProfile(i,1),app.stl.projFullRootProfile(i-1,1)],[app.stl.projFullRootProfile(i,2), app.stl.projFullRootProfile(i-1,2)],[app.stl.projFullRootProfile(i,3),app.stl.projFullRootProfile(i-1,3)], '-r', 'markerfacecolor', 'r', 'markeredgecolor', 'r', 'markersize', 5, 'linewidth', 1, 'Tag', 'projRoot');
                            plot3(app.UIAxes11,[app.stl.projFullTipProfile(i,1),app.stl.projFullTipProfile(i-1,1)],[app.stl.projFullTipProfile(i,2),app.stl.projFullTipProfile(i-1,2)],[app.stl.projFullTipProfile(i,3),app.stl.projFullTipProfile(i-1,3)], '-b', 'markerfacecolor', 'b', 'markeredgecolor', 'b', 'markersize', 5, 'linewidth', 1, 'Tag', 'projTip');
                        end
                    end
                end
                hold(app.UIAxes11, 'off');
            end
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            app.gcode.fileName = [app.FileNameEditField.Value, '.tap'];
        end

        % Button pushed function: SaveGcodeButton
        function SaveGcodeButtonPushed(app, event)
            [~,path,~] = uiputfile(app.gcode.fileName);

            cwd = pwd;
            cd(path);

            fid = fopen(app.gcode.fileName,'w');
            for i=1:1:app.gcode.numOfLines
                fprintf(fid,[app.gcode.code{i},'\n']);
            end
            fclose(fid);

            cd(cwd);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {702, 702};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {509, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout8 = uigridlayout(app.LeftPanel);
            app.GuideText = uitextarea(app.GridLayout8);
            app.GuideText.Editable = 'off';
            app.WarningText = uitextarea(app.GridLayout8);
            app.WarningText.Editable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.GCodeGeneratorTab = uitab(app.TabGroup);
            app.GCodeGeneratorTab.Title = 'GCode Generator';
            app.GridLayout7 = uigridlayout(app.GCodeGeneratorTab);
            app.GreetingText = uitextarea(app.GridLayout7);
            app.GreetingText.Editable = 'off';
            app.ModelTab = uitab(app.TabGroup);
            app.ModelTab.Title = 'Model';
            app.ModelTabGrid = uigridlayout(app.ModelTab);
            app.UIAxes = uiaxes(app.ModelTabGrid);
            title(app.UIAxes, '.STL Model')
            xlabel(app.UIAxes, 'Width - X [mm]')
            ylabel(app.UIAxes, 'Length - Y [mm]')
            zlabel(app.UIAxes, 'Depth - Z [mm]')
            app.MaxEdgeSizemmSpinnerLabel = uilabel(app.ModelTabGrid);
            app.MaxEdgeSizemmSpinnerLabel.Text = 'Max. Edge Size [mm]';
            app.MaxEdgeSizemmSpinner = uispinner(app.ModelTabGrid);
            app.MaxEdgeSizemmSpinner.Step = 0.1;
            app.MaxEdgeSizemmSpinner.Limits = [0 Inf];
            app.MaxEdgeSizemmSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxEdgeSizemmSpinnerValueChanged, true);
            app.RotateXLabel = uilabel(app.ModelTabGrid);
            app.RotateXLabel.Text = 'Rotate X [°]';
            app.RotateXSpinner = uispinner(app.ModelTabGrid);
            app.RotateXSpinner.ValueChangedFcn = createCallbackFcn(app, @RotateXSpinnerValueChanged, true);
            app.RotateYLabel = uilabel(app.ModelTabGrid);
            app.RotateYLabel.Text = 'Rotate Y [°]';
            app.RotateYSpinner = uispinner(app.ModelTabGrid);
            app.RotateYSpinner.ValueChangedFcn = createCallbackFcn(app, @RotateYSpinnerValueChanged, true);
            app.RotateZLabel = uilabel(app.ModelTabGrid);
            app.RotateZLabel.Text = 'Rotate Z [°]';
            app.RotateZSpinner = uispinner(app.ModelTabGrid);
            app.RotateZSpinner.ValueChangedFcn = createCallbackFcn(app, @RotateZSpinnerValueChanged, true);
            app.RootPlaneXcoordmmEditFieldLabel = uilabel(app.ModelTabGrid);
            app.RootPlaneXcoordmmEditFieldLabel.Text = {'Root Plane'; 'X coord. [mm]'};
            app.RootPlaneXcoordmmEditField = uieditfield(app.ModelTabGrid, 'numeric');
            app.RootPlaneXcoordmmEditField.ValueChangedFcn = createCallbackFcn(app, @RootPlaneXcoordmmEditFieldValueChanged, true);
            app.TipPlaneXcoordmmEditFieldLabel = uilabel(app.ModelTabGrid);
            app.TipPlaneXcoordmmEditFieldLabel.Text = {'Tip Plane'; 'X coord. [mm]'};
            app.TipPlaneXcoordmmEditField = uieditfield(app.ModelTabGrid, 'numeric');
            app.TipPlaneXcoordmmEditField.ValueChangedFcn = createCallbackFcn(app, @TipPlaneXcoordmmEditFieldValueChanged, true);
            app.MinEdgeSizemmSpinnerLabel = uilabel(app.ModelTabGrid);
            app.MinEdgeSizemmSpinnerLabel.Text = 'Min. Edge Size [mm]';
            app.MinEdgeSizemmSpinner = uispinner(app.ModelTabGrid);
            app.MinEdgeSizemmSpinner.Step = 0.1;
            app.MinEdgeSizemmSpinner.Limits = [0 Inf];
            app.MinEdgeSizemmSpinner.ValueChangedFcn = createCallbackFcn(app, @MinEdgeSizemmSpinnerValueChanged, true);
            app.LoadSTLButton = uibutton(app.ModelTabGrid, 'push');
            app.LoadSTLButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSTLButtonPushed, true);
            app.LoadSTLButton.Text = 'Load .STL';
            app.GenerateProfilesButton = uibutton(app.ModelTabGrid, 'push');
            app.GenerateProfilesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateProfilesButtonPushed, true);
            app.GenerateProfilesButton.Text = 'Generate Profiles';
            app.GeometryDropDownLabel = uilabel(app.ModelTabGrid);
            app.GeometryDropDownLabel.Text = 'Geometry';
            app.GeometryDropDown = uidropdown(app.ModelTabGrid);
            app.GeometryDropDown.Items = {'Straight', 'Tapered'};
            app.GeometryDropDown.Value = 'Straight';
            app.ProfilesTab = uitab(app.TabGroup);
            app.ProfilesTab.Title = 'Profiles';
            app.GridLayout2 = uigridlayout(app.ProfilesTab);
            app.UIAxes7 = uiaxes(app.GridLayout2);
            title(app.UIAxes7, 'Tip Cut')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes6 = uiaxes(app.GridLayout2);
            title(app.UIAxes6, 'Root Cut')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.NoPointsEditFieldLabel = uilabel(app.GridLayout2);
            app.NoPointsEditFieldLabel.Text = 'No. Points';
            app.NoPointsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NoPointsEditField.Limits = [0 480];
            app.NoPointsEditField.ValueChangedFcn = createCallbackFcn(app, @NoPointsEditFieldValueChanged, true);
            app.KerfEditFieldLabel = uilabel(app.GridLayout2);
            app.KerfEditFieldLabel.Text = 'Kerf';
            app.KerfEditField = uieditfield(app.GridLayout2, 'numeric');
            app.KerfEditField.ValueChangedFcn = createCallbackFcn(app, @KerfEditFieldValueChanged, true);
            app.BilletTab = uitab(app.TabGroup);
            app.BilletTab.Title = 'Billet';
            app.GridLayout3 = uigridlayout(app.BilletTab);
            app.UIAxes5 = uiaxes(app.GridLayout3);
            title(app.UIAxes5, 'Front View')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout3);
            title(app.UIAxes3, 'Top View')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout3);
            title(app.UIAxes4, 'Side View')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.TranslateXSpinner_2Label = uilabel(app.GridLayout3);
            app.TranslateXSpinner_2Label.Text = 'Translate X';
            app.TranslateXSpinner_2 = uispinner(app.GridLayout3);
            app.TranslateXSpinner_2.ValueChangedFcn = createCallbackFcn(app, @TranslateXSpinner_2ValueChanged, true);
            app.XmmEditField_2Label = uilabel(app.GridLayout3);
            app.XmmEditField_2Label.Text = '+X [mm]';
            app.XmmEditField_2 = uieditfield(app.GridLayout3, 'numeric');
            app.XmmEditFieldLabel = uilabel(app.GridLayout3);
            app.XmmEditFieldLabel.Text = '-X [mm]';
            app.XmmEditField = uieditfield(app.GridLayout3, 'numeric');
            app.TranslateYSpinner_2Label = uilabel(app.GridLayout3);
            app.TranslateYSpinner_2Label.Text = 'Translate Y';
            app.TranslateYSpinner_2 = uispinner(app.GridLayout3);
            app.TranslateYSpinner_2.ValueChangedFcn = createCallbackFcn(app, @TranslateYSpinner_2ValueChanged, true);
            app.TranslateZSpinner_2Label = uilabel(app.GridLayout3);
            app.TranslateZSpinner_2Label.Text = 'Translate Z';
            app.TranslateZSpinner_2 = uispinner(app.GridLayout3);
            app.TranslateZSpinner_2.ValueChangedFcn = createCallbackFcn(app, @TranslateZSpinner_2ValueChanged, true);
            app.YmmEditField_2Label = uilabel(app.GridLayout3);
            app.YmmEditField_2Label.Text = '+Y [mm]';
            app.YmmEditField_2 = uieditfield(app.GridLayout3, 'numeric');
            app.ZmmEditField_2Label = uilabel(app.GridLayout3);
            app.ZmmEditField_2Label.Text = '+Z [mm]';
            app.ZmmEditField_2 = uieditfield(app.GridLayout3, 'numeric');
            app.WidthmmEditField_2Label = uilabel(app.GridLayout3);
            app.WidthmmEditField_2Label.Text = 'Width [mm]';
            app.WidthmmEditField_2 = uieditfield(app.GridLayout3, 'numeric');
            app.WidthmmEditField_2.Limits = [0 Inf];
            app.WidthmmEditField_2.ValueChangedFcn = createCallbackFcn(app, @WidthmmEditField_2ValueChanged, true);
            app.LengthmmEditField_2Label = uilabel(app.GridLayout3);
            app.LengthmmEditField_2Label.Text = 'Length [mm]';
            app.LengthmmEditField_2 = uieditfield(app.GridLayout3, 'numeric');
            app.LengthmmEditField_2.Limits = [0 Inf];
            app.LengthmmEditField_2.ValueChangedFcn = createCallbackFcn(app, @LengthmmEditField_2ValueChanged, true);
            app.DepthmmEditField_2Label = uilabel(app.GridLayout3);
            app.DepthmmEditField_2Label.Text = 'Depth [mm]';
            app.DepthmmEditField_2 = uieditfield(app.GridLayout3, 'numeric');
            app.DepthmmEditField_2.Limits = [0 Inf];
            app.DepthmmEditField_2.ValueChangedFcn = createCallbackFcn(app, @DepthmmEditField_2ValueChanged, true);
            app.LoadBilletButton = uibutton(app.GridLayout3, 'push');
            app.LoadBilletButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBilletButtonPushed, true);
            app.LoadBilletButton.Text = 'Load Billet';
            app.InsertModelButton = uibutton(app.GridLayout3, 'push');
            app.InsertModelButton.ButtonPushedFcn = createCallbackFcn(app, @InsertModelButtonPushed, true);
            app.InsertModelButton.Text = 'Insert Model';
            app.YmmEditFieldLabel = uilabel(app.GridLayout3);
            app.YmmEditFieldLabel.Text = '-Y [mm]';
            app.YmmEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ZmmEditFieldLabel = uilabel(app.GridLayout3);
            app.ZmmEditFieldLabel.Text = '-Z [mm]';
            app.ZmmEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MachineTab = uitab(app.TabGroup);
            app.MachineTab.Title = 'Machine';
            app.GridLayout4 = uigridlayout(app.MachineTab);
            app.UIAxes2 = uiaxes(app.GridLayout4);
            title(app.UIAxes2, 'CNC')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Z')
            zlabel(app.UIAxes2, 'Y')
            app.TranslateXmmSpinnerLabel = uilabel(app.GridLayout4);
            app.TranslateXmmSpinnerLabel.Text = 'Translate X [mm]';
            app.TranslateXmmSpinner = uispinner(app.GridLayout4);
            app.TranslateXmmSpinner.ValueChangedFcn = createCallbackFcn(app, @TranslateXmmSpinnerValueChanged, true);
            app.LoadCNCButton = uibutton(app.GridLayout4, 'push');
            app.LoadCNCButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCNCButtonPushed, true);
            app.LoadCNCButton.Text = 'Load CNC';
            app.InsertBilletButton = uibutton(app.GridLayout4, 'push');
            app.InsertBilletButton.ButtonPushedFcn = createCallbackFcn(app, @InsertBilletButtonPushed, true);
            app.InsertBilletButton.Text = 'Insert Billet';
            app.InsertModelButton_2 = uibutton(app.GridLayout4, 'push');
            app.InsertModelButton_2.ButtonPushedFcn = createCallbackFcn(app, @InsertModelButton_2Pushed, true);
            app.InsertModelButton_2.Text = 'Insert Model';
            app.TranslateYmmSpinnerLabel = uilabel(app.GridLayout4);
            app.TranslateYmmSpinnerLabel.Text = 'Translate Y [mm]';
            app.TranslateYmmSpinner = uispinner(app.GridLayout4);
            app.TranslateYmmSpinner.ValueChangedFcn = createCallbackFcn(app, @TranslateYmmSpinnerValueChanged, true);
            app.TranslateZmmSpinnerLabel = uilabel(app.GridLayout4);
            app.TranslateZmmSpinnerLabel.Text = 'Translate Z [mm]';
            app.TranslateZmmSpinner = uispinner(app.GridLayout4);
            app.TranslateZmmSpinner.ValueChangedFcn = createCallbackFcn(app, @TranslateZmmSpinnerValueChanged, true);
            app.CuttingTab = uitab(app.TabGroup);
            app.CuttingTab.Title = 'Cutting';
            app.GridLayout5 = uigridlayout(app.CuttingTab);
            app.UIAxes8 = uiaxes(app.GridLayout5);
            title(app.UIAxes8, 'Root Projection')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes8.ButtonDownFcn = createCallbackFcn(app, @UIAxes8ButtonDown, true);
            app.UIAxes9 = uiaxes(app.GridLayout5);
            title(app.UIAxes9, 'Tip Projection')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            zlabel(app.UIAxes9, 'Z')
            app.UIAxes9.ButtonDownFcn = createCallbackFcn(app, @UIAxes9ButtonDown, true);
            app.InsertProjectedProfilesButton = uibutton(app.GridLayout5, 'push');
            app.InsertProjectedProfilesButton.ButtonPushedFcn = createCallbackFcn(app, @InsertProjectedProfilesButtonPushed, true);
            app.InsertProjectedProfilesButton.Text = 'Insert Projected Profiles';
            app.FoamEntryDropDownLabel = uilabel(app.GridLayout5);
            app.FoamEntryDropDownLabel.Text = 'Foam Entry';
            app.FoamEntryDropDown = uidropdown(app.GridLayout5);
            app.FoamEntryDropDown.Items = {'Select', 'Through', 'Avoid'};
            app.FoamEntryDropDown.ValueChangedFcn = createCallbackFcn(app, @FoamEntryDropDownValueChanged, true);
            app.FoamEntryDropDown.Value = 'Select';
            app.DirectionOfCutDropDownLabel = uilabel(app.GridLayout5);
            app.DirectionOfCutDropDownLabel.Text = 'Direction Of Cut';
            app.DirectionOfCutDropDown = uidropdown(app.GridLayout5);
            app.DirectionOfCutDropDown.Items = {'CCW', 'CW'};
            app.DirectionOfCutDropDown.Value = 'CW';
            app.SelectEntryPointSwitchLabel = uilabel(app.GridLayout5);
            app.SelectEntryPointSwitchLabel.Text = 'Select Entry Point';
            app.SelectEntryPointSwitch = uiswitch(app.GridLayout5, 'slider');
            app.SelectEntryPointSwitch.Items = {'No', 'Yes'};
            app.SelectEntryPointSwitch.ValueChangedFcn = createCallbackFcn(app, @SelectEntryPointSwitchValueChanged, true);
            app.SelectEntryPointSwitch.Value = 'No';
            app.SelectStartPointSwitchLabel = uilabel(app.GridLayout5);
            app.SelectStartPointSwitchLabel.Text = 'Select Start Point';
            app.SelectStartPointSwitch = uiswitch(app.GridLayout5, 'slider');
            app.SelectStartPointSwitch.Items = {'No', 'Yes'};
            app.SelectStartPointSwitch.ValueChangedFcn = createCallbackFcn(app, @SelectStartPointSwitchValueChanged, true);
            app.SelectStartPointSwitch.Value = 'No';
            app.CuttingVisTab = uitab(app.TabGroup);
            app.CuttingVisTab.Title = 'Cutting Vis';
            app.GridLayout9 = uigridlayout(app.CuttingVisTab);
            app.UIAxes10 = uiaxes(app.GridLayout9);
            title(app.UIAxes10, 'CNC')
            xlabel(app.UIAxes10, 'X')
            ylabel(app.UIAxes10, 'Y')
            zlabel(app.UIAxes10, 'Z')
            app.SimulateCutButton = uibutton(app.GridLayout9, 'push');
            app.SimulateCutButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateCutButtonPushed, true);
            app.SimulateCutButton.Text = 'Simulate Cut';
            app.GcodeTab = uitab(app.TabGroup);
            app.GcodeTab.Title = 'G-code';
            app.GridLayout6 = uigridlayout(app.GcodeTab);
            app.UIAxes11 = uiaxes(app.GridLayout6);
            title(app.UIAxes11, 'CNC')
            xlabel(app.UIAxes11, 'X')
            ylabel(app.UIAxes11, 'Y')
            zlabel(app.UIAxes11, 'Z')
            app.Label_4 = uilabel(app.GridLayout6);
            app.Label_4.Text = '';
            app.TextArea_5 = uitextarea(app.GridLayout6);
            app.RunGcodeButton = uibutton(app.GridLayout6, 'push');
            app.RunGcodeButton.ButtonPushedFcn = createCallbackFcn(app, @RunGcodeButtonPushed, true);
            app.RunGcodeButton.Text = 'Run G-code';
            app.SaveGcodeButton = uibutton(app.GridLayout6, 'push');
            app.SaveGcodeButton.ButtonPushedFcn = createCallbackFcn(app, @SaveGcodeButtonPushed, true);
            app.SaveGcodeButton.Text = 'Save G-code';
            app.FileNameLabel = uilabel(app.GridLayout6);
            app.FileNameLabel.Text = 'File Name:';
            app.FileNameEditField = uieditfield(app.GridLayout6, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.FeedRateEditFieldLabel = uilabel(app.GridLayout6);
            app.FeedRateEditFieldLabel.Text = 'Feed Rate';
            app.FeedRateEditField = uieditfield(app.GridLayout6, 'numeric');
            app.FeedRateEditField.Limits = [0 Inf];
            app.FeedRateEditField.Value = 80;
            app.GenerateGcodeButton = uibutton(app.GridLayout6, 'push');
            app.GenerateGcodeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateGcodeButtonPushed, true);
            app.GenerateGcodeButton.Text = 'Generate G-code';
            app.Slider = uislider(app.GridLayout6);
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GCodeGen_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end